classdef programa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HRVMetricsUIFigure              matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadTab                         matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        FileInfo                        matlab.ui.container.Panel
        SelectedchannelDropDownLabel    matlab.ui.control.Label
        SelectedchannelDropDown         matlab.ui.control.DropDown
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        SamplingfrequencyEditFieldLabel  matlab.ui.control.Label
        SamplingfrequencyEditField      matlab.ui.control.EditField
        NumberofchannelsEditFieldLabel  matlab.ui.control.Label
        NumberofchannelsEditField       matlab.ui.control.EditField
        DurationEditFieldLabel          matlab.ui.control.Label
        DurationEditField               matlab.ui.control.EditField
        NumberofsamplesEditFieldLabel   matlab.ui.control.Label
        NumberofsamplesEditField        matlab.ui.control.EditField
        TimeselectionPanel              matlab.ui.container.Panel
        UpdateselectionButton           matlab.ui.control.Button
        DurationLabel_2                 matlab.ui.control.Label
        useSameInterval                 matlab.ui.control.CheckBox
        SamplesLabel                    matlab.ui.control.Label
        ControlpanelPanel               matlab.ui.container.Panel
        LoadfileButton                  matlab.ui.control.Button
        CleardataButton                 matlab.ui.control.Button
        PlotcontrolPanel_2              matlab.ui.container.Panel
        FitalldataButton                matlab.ui.control.Button
        FitverticallyButton             matlab.ui.control.Button
        FitButton                       matlab.ui.control.Button
        SizesEditFieldLabel             matlab.ui.control.Label
        SizesEditField                  matlab.ui.control.NumericEditField
        PossEditFieldLabel              matlab.ui.control.Label
        PossEditField                   matlab.ui.control.NumericEditField
        ToolsButtonGroup                matlab.ui.container.ButtonGroup
        ZoomButton                      matlab.ui.control.ToggleButton
        PanButton                       matlab.ui.control.ToggleButton
        PeakdetTab                      matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        PeaktopeakanalysisPanel         matlab.ui.container.Panel
        NumberofpeaksEditFieldLabel     matlab.ui.control.Label
        NumberofpeaksEditField          matlab.ui.control.EditField
        AverageBPMEditFieldLabel        matlab.ui.control.Label
        AverageBPMEditField             matlab.ui.control.EditField
        BPMStddeviationEditFieldLabel   matlab.ui.control.Label
        BPMStddeviationEditField        matlab.ui.control.EditField
        PlotcontrolPanel                matlab.ui.container.Panel
        FitalldataButton_2              matlab.ui.control.Button
        FitverticallyButton_2           matlab.ui.control.Button
        ToolsButtonGroup_2              matlab.ui.container.ButtonGroup
        ZoomButton_2                    matlab.ui.control.ToggleButton
        PanButton_2                     matlab.ui.control.ToggleButton
        FitButton_2                     matlab.ui.control.Button
        SizesEditField_2Label           matlab.ui.control.Label
        SizesEditField_2                matlab.ui.control.NumericEditField
        PossEditField_2Label            matlab.ui.control.Label
        PossEditField_2                 matlab.ui.control.NumericEditField
        TachogramTab                    matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes4_2                       matlab.ui.control.UIAxes
        OutlierscontrolPanel            matlab.ui.container.Panel
        RemoveoutliersCheckBox          matlab.ui.control.CheckBox
        MethodButtonGroup               matlab.ui.container.ButtonGroup
        MeanButton                      matlab.ui.control.RadioButton
        MedianButton                    matlab.ui.control.RadioButton
        MovingmeanButton                matlab.ui.control.RadioButton
        MovingmedianButton              matlab.ui.control.RadioButton
        ComparetoprevButton             matlab.ui.control.RadioButton
        WindowsizesEditFieldLabel       matlab.ui.control.Label
        WindowsizesEditField            matlab.ui.control.NumericEditField
        NumberofoutliersEditFieldLabel  matlab.ui.control.Label
        NumberofoutliersEditField       matlab.ui.control.EditField
        ComparetoprevthresholdEditFieldLabel  matlab.ui.control.Label
        ComparetoprevthresholdEditField  matlab.ui.control.NumericEditField
        TimedomainTab                   matlab.ui.container.Tab
        StatisticalmetricsPanel         matlab.ui.container.Panel
        SDNNEditFieldLabel              matlab.ui.control.Label
        SDNNEditField                   matlab.ui.control.EditField
        SDANNEditFieldLabel             matlab.ui.control.Label
        SDANNEditField                  matlab.ui.control.EditField
        RMSSDEditFieldLabel             matlab.ui.control.Label
        RMSSDEditField                  matlab.ui.control.EditField
        SDNNindexEditFieldLabel         matlab.ui.control.Label
        SDNNindexEditField              matlab.ui.control.EditField
        SDSDEditFieldLabel              matlab.ui.control.Label
        SDSDEditField                   matlab.ui.control.EditField
        NN50EditFieldLabel              matlab.ui.control.Label
        NN50EditField                   matlab.ui.control.EditField
        pNN50EditFieldLabel             matlab.ui.control.Label
        pNN50EditField                  matlab.ui.control.EditField
        NN50Label                       matlab.ui.control.Label
        NN50iEditField                  matlab.ui.control.EditField
        NN50Label_2                     matlab.ui.control.Label
        NN50dEditField                  matlab.ui.control.EditField
        GeometricmetricsPanel           matlab.ui.container.Panel
        HRVtriangularindexEditFieldLabel  matlab.ui.control.Label
        HRVtriangularindexEditField     matlab.ui.control.EditField
        TINNEditFieldLabel              matlab.ui.control.Label
        TINNEditField                   matlab.ui.control.EditField
        ShowtriangularinterpolationCheckBox  matlab.ui.control.CheckBox
        LogaritmicindexEditFieldLabel   matlab.ui.control.Label
        LogaritmicindexEditField        matlab.ui.control.EditField
        ShowexponencialinterpolationCheckBox  matlab.ui.control.CheckBox
        GeometryAxis                    matlab.ui.control.UIAxes
        GeometryAxis2                   matlab.ui.control.UIAxes
        FrequencydomainTab              matlab.ui.container.Tab
        UIAxes6                         matlab.ui.control.UIAxes
        SpectrummetricsPanel            matlab.ui.container.Panel
        VLFPowerEditFieldLabel          matlab.ui.control.Label
        VLFPowerEditField               matlab.ui.control.EditField
        LFPowerEditFieldLabel           matlab.ui.control.Label
        LFPowerEditField                matlab.ui.control.EditField
        HFPowerEditFieldLabel           matlab.ui.control.Label
        HFPowerEditField                matlab.ui.control.EditField
        VLFEditFieldLabel               matlab.ui.control.Label
        VLFEditField                    matlab.ui.control.EditField
        LFEditFieldLabel                matlab.ui.control.Label
        LFEditField                     matlab.ui.control.EditField
        HFEditFieldLabel                matlab.ui.control.Label
        HFEditField                     matlab.ui.control.EditField
        LFnormEditFieldLabel            matlab.ui.control.Label
        LFnormEditField                 matlab.ui.control.EditField
        HFnormEditFieldLabel            matlab.ui.control.Label
        HFnormEditField                 matlab.ui.control.EditField
        LFHFEditFieldLabel              matlab.ui.control.Label
        LFHFEditField                   matlab.ui.control.EditField
        PeakLFEditFieldLabel            matlab.ui.control.Label
        PeakLFEditField                 matlab.ui.control.EditField
        PeakHFEditFieldLabel            matlab.ui.control.Label
        PeakHFEditField                 matlab.ui.control.EditField
        SpectrumcontrolButtonGroup      matlab.ui.container.ButtonGroup
        MetricsDropDownLabel            matlab.ui.control.Label
        MetricsDropDown                 matlab.ui.control.DropDown
        ResampleinterpDropDownLabel     matlab.ui.control.Label
        ResampleinterpDropDown          matlab.ui.control.DropDown
        ARorderEditFieldLabel           matlab.ui.control.Label
        ARorderEditField                matlab.ui.control.NumericEditField
        WelchovrEditFieldLabel          matlab.ui.control.Label
        WelchovrEditField               matlab.ui.control.NumericEditField
        WindowminEditFieldLabel         matlab.ui.control.Label
        WindowminEditField              matlab.ui.control.NumericEditField
        LogscaleCheckBox                matlab.ui.control.CheckBox
        BandfrequenciesPanel            matlab.ui.container.Panel
        VLFHzLabel                      matlab.ui.control.Label
        VLF1                            matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        VLF2                            matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        LF2                             matlab.ui.control.NumericEditField
        HFHzLabel                       matlab.ui.control.Label
        HF1                             matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        HF2                             matlab.ui.control.NumericEditField
        LFHzLabel                       matlab.ui.control.Label
        LF1                             matlab.ui.control.NumericEditField
        MethodstoplotPanel              matlab.ui.container.Panel
        LombCheckBox                    matlab.ui.control.CheckBox
        ARCheckBox                      matlab.ui.control.CheckBox
        WelchCheckBox                   matlab.ui.control.CheckBox
        FFTCheckBox                     matlab.ui.control.CheckBox
        TrendremovalorderEditFieldLabel  matlab.ui.control.Label
        TrendremovalorderEditField      matlab.ui.control.NumericEditField
        NonlinearanalysisTab            matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        PoincarplotTab                  matlab.ui.container.Tab
        UIAxes7                         matlab.ui.control.UIAxes
        PoincarmetricsPanel             matlab.ui.container.Panel
        SD1EditFieldLabel               matlab.ui.control.Label
        SD1EditField                    matlab.ui.control.EditField
        SD2EditFieldLabel               matlab.ui.control.Label
        SD2EditField                    matlab.ui.control.EditField
        SD3EditFieldLabel               matlab.ui.control.Label
        SD3EditField                    matlab.ui.control.EditField
        ControlparametersPanel_2        matlab.ui.container.Panel
        ShowellipseaxisCheckBox         matlab.ui.control.CheckBox
        LagEditFieldLabel               matlab.ui.control.Label
        LagEditField                    matlab.ui.control.NumericEditField
        PlotorderDropDownLabel          matlab.ui.control.Label
        PlotorderDropDown               matlab.ui.control.DropDown
        ViewXYButton                    matlab.ui.control.Button
        ViewXZButton                    matlab.ui.control.Button
        ViewZYButton                    matlab.ui.control.Button
        IsoviewButton                   matlab.ui.control.Button
        DFATab                          matlab.ui.container.Tab
        UIAxes8                         matlab.ui.control.UIAxes
        DFAmetricsPanel                 matlab.ui.container.Panel
        Alpha1EditFieldLabel            matlab.ui.control.Label
        Alpha1EditField                 matlab.ui.control.EditField
        Alpha2EditFieldLabel            matlab.ui.control.Label
        Alpha2EditField                 matlab.ui.control.EditField
        ControlparametersPanel          matlab.ui.container.Panel
        SamplevectorinterpolationButtonGroup  matlab.ui.container.ButtonGroup
        LinearButton                    matlab.ui.control.RadioButton
        LogarithmicButton               matlab.ui.control.RadioButton
        LeastSquareRegressionorderLabel  matlab.ui.control.Label
        LeastSquareRegressionorderEditField  matlab.ui.control.NumericEditField
        BreakpointbeatsEditFieldLabel   matlab.ui.control.Label
        BreakpointbeatsEditField        matlab.ui.control.NumericEditField
        MinimumsegmentsizeEditFieldLabel  matlab.ui.control.Label
        MinimumsegmentsizeEditField     matlab.ui.control.NumericEditField
        MaximumsegmentsizeEditFieldLabel  matlab.ui.control.Label
        MaximumsegmentsizeEditField     matlab.ui.control.NumericEditField
        NumberofsegmentsizesEditFieldLabel  matlab.ui.control.Label
        NumberofsegmentsizesEditField   matlab.ui.control.NumericEditField
        EntropyTab                      matlab.ui.container.Tab
        ApproximateEntropyPanel         matlab.ui.container.Panel
        ApproximateEntropyLabel         matlab.ui.control.Label
        ApproximateEntropyEditField     matlab.ui.control.NumericEditField
        TolerancerEditFieldLabel        matlab.ui.control.Label
        TolerancerEditField             matlab.ui.control.NumericEditField
        ResetButton                     matlab.ui.control.Button
        EmbeddingdimensionEditFieldLabel  matlab.ui.control.Label
        EmbeddingdimensionEditField     matlab.ui.control.NumericEditField
        MultiscaleEntropyMSEPanel       matlab.ui.container.Panel
        UIAxes10                        matlab.ui.control.UIAxes
        MaxscaleEditFieldLabel          matlab.ui.control.Label
        MaxscaleEditField               matlab.ui.control.NumericEditField
        SampleEntropyPanel              matlab.ui.container.Panel
        ResetButton_2                   matlab.ui.control.Button
        TolerancerEditField_2Label      matlab.ui.control.Label
        TolerancerEditField_2           matlab.ui.control.NumericEditField
        EmbeddingdimensionEditField_2Label  matlab.ui.control.Label
        EmbeddingdimensionEditField_2   matlab.ui.control.NumericEditField
        SampleEntropyEditFieldLabel     matlab.ui.control.Label
        SampleEntropyEditField          matlab.ui.control.NumericEditField
        RecurrenceplotTab               matlab.ui.container.Tab
        UIAxes9                         matlab.ui.control.UIAxes
        RecurrenceplotmetricsPanel      matlab.ui.container.Panel
        ShannonentropyEditFieldLabel    matlab.ui.control.Label
        ShannonentropyEditField         matlab.ui.control.NumericEditField
        DETEditFieldLabel               matlab.ui.control.Label
        DETEditField                    matlab.ui.control.NumericEditField
        RecurrencerateRECEditField_2Label  matlab.ui.control.Label
        RecurrencerateRECEditField_2    matlab.ui.control.EditField
        RecurrenceparameterscontrolPanel  matlab.ui.container.Panel
        ThresholdrEditFieldLabel        matlab.ui.control.Label
        ThresholdrEditField             matlab.ui.control.NumericEditField
        EmbeddingdimensionEditField_3Label  matlab.ui.control.Label
        EmbeddingdimensionEditField_3   matlab.ui.control.NumericEditField
        LagEditField_2Label             matlab.ui.control.Label
        LagEditField_2                  matlab.ui.control.NumericEditField
        ReportTab                       matlab.ui.container.Tab
        GeneratereportButton            matlab.ui.control.Button
        FilenameEditField_2Label        matlab.ui.control.Label
        FilenameEditField_2             matlab.ui.control.EditField
        PathtosaveEditFieldLabel        matlab.ui.control.Label
        PathtosaveEditField             matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        Fs % Description
        ecg_data    % include all channels of ECG and Fs
        ecg_all     % all channels of the ECG
        ecg % Description
        startTime % Description
        duration % Description
        intervalInSecondsForPeaks
        peaks % Description
        peak_locs % Description
        ecg_loaded = false
        peak_det_done = false % Description
        peak_to_peak % Peak-to-peak intervals (in seconds)
        peak_to_peak_adjacent_diff  % Differences between adjacent peak-to-peak intervals
        peak_tab_done
        tachogram_tab_done
        geom_tab_done
        timeseries_tab_done
        freq_tab_done
        poincare_tab_done
        entropy_tab_done
        DFA_tab_done
        recurrence_tab_done
        peak_to_peak_resampled % Description
        endTime % Description
        removeOutliersMethod % Description
        numberOfChannels
        selectedChannel
        
        % app metrics
        % Geometric
        metric_Geom_HRVTriangularIndex % Description
        metric_Geom_TINN
        metric_Geom_LogaritmicIndex
        % Statistical (time series)
        metric_TS_SDNN
        metric_TS_SDANN
        metric_TS_RMSSD
        metric_TS_SDNNindex
        metric_TS_SDSD
        metric_TS_NN50
        metric_TS_NN50i
        metric_TS_NN50d
        metric_TS_pNN50
        % Frequency metrics
        vlf_power
        lf_power
        hf_power
        vlf_rel
        lf_rel
        hf_rel
        lf_norm
        hf_norm
        lf_to_hf
        lf_peak_freq
        hf_peak_freq
        % Non linear metrics
        % Poincaré
        metric_NL_SD1
        metric_NL_SD2
        metric_NL_SD3
        %Entropy
        metric_NL_aproxEntropy
        metric_NL_sampleEntropy
        metric_NL_MSE
        % DFA
        metric_NL_A1
        metric_NL_A2
        % Recurrence
        metric_NL_REC
    end
    
    methods (Access = private)
        
        function runPeakDetection(app)

            % Adjust time setters
            if(app.useSameInterval.Value)
                start_time = app.startTime;
                end_time = app.endTime;
            else
                start_time = 0;
                end_time = app.duration;
            end
            
            s = max(round(start_time*app.Fs),1) : min(round(end_time*app.Fs), length(app.ecg));
            t = (s-1)/app.Fs;
            app.intervalInSecondsForPeaks = t;
            
            % Info peak analysis
            p_l = app.peak_locs(app.peak_locs > s(1) & app.peak_locs < s(end));
            BPM = round(60./(diff(p_l)/app.Fs));
            avr_BPM = mean(BPM);
            std_BPM = std(BPM);
            app.NumberofpeaksEditField.Value = num2str(length(p_l));
            app.AverageBPMEditField.Value = num2str(round(avr_BPM,2));
            app.BPMStddeviationEditField.Value = num2str(round(std_BPM,2));
            
            % Plot peaks
            cla(app.UIAxes2);
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, t, app.ecg(s), 'Color',[0 0.4470 0.7410], 'Marker','none');
            % local improvment
            n = 5;
            pts = zeros(2*n+1, length(app.peak_locs));
            for i = -n:n
                idx = max(1, app.peak_locs + i);
                idx = min(length(app.ecg), idx);
                pts(i+n+1,:) = app.ecg(idx);
            end
            pks = max(pts, [], 1);
            plot(app.UIAxes2, (app.peak_locs)./app.Fs, pks, 'Color','r', 'LineStyle', 'none', 'Marker','x');
            hold(app.UIAxes2, 'off');
            axis(app.UIAxes2, 'tight');
            y_l = ylim(app.UIAxes);
            ylh = y_l(2) - y_l(1);
            ylim(app.UIAxes2, [y_l(1)-ylh*0.1, y_l(2)+ylh*0.1]);
            xlabel(app.UIAxes2, 'Time (s)');
            ylabel(app.UIAxes2, 'Amplitude (a.u.)');
            title(app.UIAxes2, 'Peak detection');
            
            app.PossEditField_2.Value = t(1);
            app.SizesEditField_2.Value = min(10, t(end) - t(1));
            
            zoom(app.UIAxes2, 'on');
        end
        

        function getPeaks(app)
            d = uiprogressdlg(app.HRVMetricsUIFigure, 'Title', 'Please wait...', 'Message', 'Detecting R-peaks', 'Indeterminate', 'on');
            
            % Get peaks
            if(app.useSameInterval.Value)
                sample = max(round(app.startTime*app.Fs),1) : min(round(app.endTime*app.Fs), length(app.ecg));
            else
                sample = 1 : round(app.duration*app.Fs);
            end
            
            [app.peak_locs] = find_peaks(app.ecg(sample), app.Fs);
            app.peak_det_done = true;
            app.peak_locs = app.peak_locs + sample(1) - 1;
            app.peaks  = app.ecg(app.peak_locs);

            app.peak_to_peak = diff(app.peak_locs)./app.Fs;
            app.peak_to_peak_adjacent_diff = diff(app.peak_to_peak);
            
            close(d);
        end
        
        function updateTachogram(app)
            d = uiprogressdlg(app.HRVMetricsUIFigure, 'Title', 'Please wait...', 'Message', 'Plotting tachograms...', 'Indeterminate', 'on');
            
            % any change to the tachogram (outlier method changed) will report in other metrics
            app.geom_tab_done = false;
            app.timeseries_tab_done = false;
            app.freq_tab_done = false;
            app.poincare_tab_done = false;
            app.entropy_tab_done = false;
            app.DFA_tab_done = false;
            app.recurrence_tab_done = false;
            
            app.peak_to_peak = diff(app.peak_locs)./app.Fs;
            if(app.RemoveoutliersCheckBox.Value)
                method = app.removeOutliersMethod;
                if(strcmp(method,'movmean') || strcmp(method,'movmedian'))
                    window_size = app.WindowsizesEditField.Value;
                    [~, npl] = rmoutliers(app.peak_to_peak, app.removeOutliersMethod, window_size);
                elseif(strcmp(method,'mean') || strcmp(method,'median'))
                    [~, npl] = rmoutliers(app.peak_to_peak, app.removeOutliersMethod);
                elseif(strcmp(method,'compare'))
                    thr = (app.ComparetoprevthresholdEditField.Value)/100;
                    npl = false(1, length(app.peak_to_peak));
                    jump_next = false;
                    for i = 2:length(app.peak_to_peak)
                        if(jump_next)
                            jump_next = false;
                            continue;
                        end
                        
                        if(app.peak_to_peak(i) < (1-thr) * app.peak_to_peak(i-1) || app.peak_to_peak(i) > (1+thr) * app.peak_to_peak(i-1))
                            npl(i) = true;
                            jump_next = true;
                        end
                    end
                end
                
                % fill whole from removed samples
                % with mean from adjacent non-outlier values
                mark = false;
                for i = 1:length(npl)
                    if(npl(i))
                        if(~mark)
                            v1p = i;
                            v1 = app.peak_to_peak(max(1,i-1));
                            mark = true;
                        end
                    else
                        if(mark)
                            v2p = max(1,i-1);
                            v2 = app.peak_to_peak(i);
                            mark = false;
                            tt = max(1,(v1p-1)):(v2p+1);
                            app.peak_to_peak(tt) = linspace(v1, v2, length(tt));
                        end
                    end
                    
                end
                
                app.NumberofoutliersEditField.Value = num2str(sum(npl));
            else
                app.NumberofoutliersEditField.Value = '';
            end

            app.peak_to_peak_adjacent_diff = diff(app.peak_to_peak);
            
            if(app.useSameInterval.Value)
                start_time = app.startTime;
                end_time = app.endTime;
            else
                start_time = 0;
                end_time = app.duration;
            end
            s = max(round(start_time*app.Fs),1) : min(round(end_time*app.Fs), length(app.ecg));
            t = s/app.Fs;
            
            cla(app.UIAxes4);
            plot(app.UIAxes4, t(1) + app.peak_locs(1:end-1)/app.Fs, app.peak_to_peak);
            hold(app.UIAxes4, 'on');
            plot(app.UIAxes4, t(1) + app.peak_locs(1:end-1)/app.Fs, movmean(app.peak_to_peak, 100), 'LineWidth', 2);
            axis(app.UIAxes4, 'tight');
            title(app.UIAxes4, 'RR Intervals');
            xlabel(app.UIAxes4, 'Time (s)');
            ylabel(app.UIAxes4, 'Interval duration (s)');

            cla(app.UIAxes4_2);
            plot(app.UIAxes4_2, t(1) + app.peak_locs(1:end-1)/app.Fs, 60./app.peak_to_peak);
            hold(app.UIAxes4_2, 'on');
            plot(app.UIAxes4_2, t(1) + app.peak_locs(1:end-1)/app.Fs, movmean(60./app.peak_to_peak, 100), 'LineWidth', 2);
            axis(app.UIAxes4_2, 'tight');
            title(app.UIAxes4_2, 'Heart rate');
            xlabel(app.UIAxes4_2, 'Time (s)');
            ylabel(app.UIAxes4_2, 'Heart rate (BPM)');
            
            close(d);
        end
    
        function updateGeometry(app)
            d = uiprogressdlg(app.HRVMetricsUIFigure, 'Title', 'Please wait...', 'Message', 'Calculating Geometric metrics...', 'Indeterminate', 'on');
            
            % plot histogram
            cla(app.GeometryAxis);
            cla(app.GeometryAxis2);
            
            min_pp = min(app.peak_to_peak);
            max_pp = max(app.peak_to_peak);
            [counts, centers] = hist(app.peak_to_peak, (min_pp:1/128:max_pp));
            bar(app.GeometryAxis, centers, counts,'BarWidth', 1);
            title(app.GeometryAxis,'Peak to peak (NN) intervals');
            ylabel(app.GeometryAxis,'Frequency');
            xlabel(app.GeometryAxis,'Peak to peak duration (seconds)');
            
            abs_diff = abs(app.peak_to_peak_adjacent_diff);
            [counts2, centers2] = hist(abs_diff, min(abs_diff):2/128:max(abs_diff));
            bar(app.GeometryAxis2, centers2, counts2,'BarWidth', 1);
            title(app.GeometryAxis2,'Absolute difference between adjacent NN intervals');
            ylabel(app.GeometryAxis2,'Frequency');
            xlabel(app.GeometryAxis2,'Absolute difference (seconds)');
                        
            % metrics
            
            % HRV Triangular Index
            app.metric_Geom_HRVTriangularIndex = length(app.peak_to_peak) / max(counts);
            app.HRVtriangularindexEditField.Value = num2str(round(app.metric_Geom_HRVTriangularIndex,2));
            
            % TINN – (ms) Triangular Interpolation of the histogram of the NN intervals
            % Get gaussian fit
            a = max(counts);
            idx = find(counts == max(counts));
            b = centers(idx(1));
            c = std(app.peak_to_peak);
            gaussian_fit = fit(centers',counts','gauss1', 'Start', [a,b,c]);
            coefs = coeffvalues(gaussian_fit);
            gaussian_func = coefs(1) * exp(-((centers-coefs(2))/coefs(3)).^2);

            % Get triangle interpolation
            gauss_diff = diff(gaussian_func);
            gd_max = max(gauss_diff);
            gd_x_max = find(gauss_diff == gd_max);
            gauss_p_x = find(gaussian_func == max(gaussian_func));
            top = gaussian_func(gd_x_max+1) + (gauss_p_x - gd_x_max) * gd_max;
            top = (top + max(gaussian_func)) / 2;
            triangle_base = 2 * ceil(top/gd_max);
            line1 = linspace(0, top, triangle_base/2);
            line2 = linspace(top, 0, triangle_base/2);
            triangle = [zeros(1, gauss_p_x - triangle_base/2), line1, line2(2:end), zeros(1, length(centers) - gauss_p_x - triangle_base/2  + 1)];
            
           % k = (top - gaussian_func(gd_x_max)) / ()
            
            size(triangle)
            size(centers)
            % Plot triangle interpolation
            if(app.ShowtriangularinterpolationCheckBox.Value)
                hold(app.GeometryAxis,'on');
                plot(app.GeometryAxis, centers, triangle, 'LineWidth',2);
                %plot(app.GeometryAxis, centers, gaussian_func, 'LineWidth',1);
                %plot(app.GeometryAxis, centers(1:end-1), gauss_diff, 'LineWidth',1);
            end
            app.metric_Geom_TINN = triangle_base;
            app.TINNEditField.Value = [num2str(round(app.metric_Geom_TINN,2)),' ms'];
            
            % Logaritmic index
            exp_fit = fit(centers2', counts2', 'exp1', 'Start', [counts2(1), -10]);
            coefs2 = coeffvalues(exp_fit);
            exp_func = coefs2(1)*exp(centers2*coefs2(2));
            app.metric_Geom_LogaritmicIndex = coefs2(2);
            app.LogaritmicindexEditField.Value = num2str(round(app.metric_Geom_LogaritmicIndex,2));
            if(app.ShowexponencialinterpolationCheckBox.Value)
                hold(app.GeometryAxis2,'on');
                plot(app.GeometryAxis2, centers2, exp_func, 'LineWidth',2);
            end

            axis(app.GeometryAxis, 'tight');
            axis(app.GeometryAxis2, 'tight');
            
            close(d);
        end
    
        function updateTimeSeries(app)
            d = uiprogressdlg(app.HRVMetricsUIFigure, 'Title', 'Please wait...', 'Message', 'Calculating time domain metrics...', 'Indeterminate', 'on');
            % SDNN
            app.metric_TS_SDNN = std(app.peak_to_peak) * 1000;
            app.SDNNEditField.Value = [num2str(round(app.metric_TS_SDNN,2)), ' ms'];
            
            % SDANN
            cumSum = cumsum(app.peak_to_peak);
            idx1 = 1;
            sdann = [];
            sdanni = [];
            for i = 1:floor(cumSum(end)/5)
                [~, idx2] = min(abs(cumSum - i*5));
                sdann(i) = mean(app.peak_to_peak(idx1:idx2));
                sdanni(i) = std(app.peak_to_peak(idx1:idx2));
                idx1 = idx2 + 1;
            end
            app.metric_TS_SDANN = std(sdann) * 1000;
            app.SDANNEditField.Value = [num2str(round(app.metric_TS_SDANN,2)), ' ms'];
            
            % SDANN_index
            app.metric_TS_SDNNindex = mean(sdanni) * 1000;
            app.SDNNindexEditField.Value = [num2str(round(app.metric_TS_SDNNindex,2)), ' ms'];
            
            % RMSNN
            app.metric_TS_RMSSD = rms(app.peak_to_peak_adjacent_diff) * 1000;
            app.RMSSDEditField.Value = [num2str(round(app.metric_TS_RMSSD,2)), ' ms'];
            
            % SDSD
            app.metric_TS_SDSD = std(app.peak_to_peak_adjacent_diff) * 1000;
            app.SDSDEditField.Value = [num2str(round(app.metric_TS_SDSD,2)),' ms'];
            
            % NN50
            app.metric_TS_NN50 = sum(abs(app.peak_to_peak_adjacent_diff) > 0.05);    % more than 50 ms
            app.NN50EditField.Value = num2str(round(app.metric_TS_NN50,2));
            
            % NN50+
            app.metric_TS_NN50i = sum(app.peak_to_peak_adjacent_diff > 0.05);    % more than 50 ms
            app.NN50iEditField.Value = num2str(round(app.metric_TS_NN50i,2));
            
            % NN50-
            app.metric_TS_NN50d = sum(app.peak_to_peak_adjacent_diff < -0.05);    % more than 50 ms
            app.NN50dEditField.Value = num2str(round(app.metric_TS_NN50d,2));
            
            % pNN50
            app.metric_TS_pNN50 = sum(app.peak_to_peak_adjacent_diff > 0.05) / length(app.peak_to_peak);    % more than 50 ms
            app.pNN50EditField.Value = [num2str(round(app.metric_TS_pNN50,2)),' %'];
            
            close(d);
        end
    
        function updateFrequencyDomain(app)
    
            d = uiprogressdlg(app.HRVMetricsUIFigure, 'Title', 'Please wait...', 'Message', 'Calculating frequency metrics...', 'Indeterminate', 'on');
            
            nni = app.peak_to_peak;
            nni = nni(:);
            tnn = [0; cumsum(nni(1:end-1))];
            
            if(app.TrendremovalorderEditField.Value ~= -1)
                nni = detrend(nni, app.TrendremovalorderEditField.Value);
            end
            
            VLF_band = [app.VLF1.Value, app.VLF2.Value];
            LF_band =  [app.LF1.Value, app.LF2.Value];
            HF_band = [app.HF1.Value, app.HF2.Value];
            
            ar_order = app.ARorderEditField.Value;      % auto-regressive order
            welch_overlap = app.WelchovrEditField.Value; % 50 percent
            win_func = str2func('hamming');
            f_max = HF_band(2);
            f_min = VLF_band(1);
            window_minutes = app.WindowminEditField.Value; % use 5 minutes windows to average PSD
            resample_factor = 2.25;
            freq_osf = 4;
            t_max = tnn(end);
            
            % Minimal window length (in seconds) needed to resolve f_min
            t_win_min = 1/f_min;
            
            % Increase window size if it's too small
            t_win = 60 * window_minutes; % Window length in seconds
            if t_win < t_win_min
                t_win = t_win_min;
            end
            
            % In case there's not enough data for one window, use entire signal length
            num_windows = floor(t_max / t_win);
            if (num_windows < 1)
                num_windows = 1;
                t_win = floor(tnn(end)-tnn(1));
            end
            
            % Uniform sampling freq: Take at least 2x more than f_max
            fs_uni = resample_factor * f_max; %Hz
            
            % Uniform time axis
            tnn_uni = tnn(1) : 1/fs_uni : tnn(end);
            n_win_uni = floor(t_win / (1/fs_uni)); % number of samples in each window
            num_windows_uni = floor(length(tnn_uni) / n_win_uni);
            
            % Build a frequency axis
            ts = t_win / (n_win_uni-1);   % Sampling time interval
            f_res = 1 / (n_win_uni * ts); % Frequency resolution (also known as f_min of delta_f), theoretical limit
            f_res = f_res / freq_osf;     % Apply oversampling factor (causes interpolation in freq. domain)
            
            f_axis = (f_res : f_res : f_max)';
            
            % Check Nyquist criterion: We need atleast 2*f_max*t_win samples in each window to resolve f_max.
            if (n_win_uni < 2*f_max*t_win)
                warning('Nyquist criterion not met for given window length and frequency bands');
            end
            
            % Initialize outputs
            pxx_lomb  = zeros(length(f_axis), 1); calc_lomb  = app.LombCheckBox.Value;
            pxx_ar    = zeros(length(f_axis), 1); calc_ar    = app.ARCheckBox.Value;
            pxx_welch = zeros(length(f_axis), 1); calc_welch = app.WelchCheckBox.Value;
            pxx_fft = zeros(length(f_axis), 1); calc_fft   = app.FFTCheckBox.Value;
            
            % Interlopate nn-intervals
            switch app.ResampleinterpDropDown.Value
                case 'Cubic'
                    nni_uni = interp1(tnn, nni, tnn_uni, 'pchip')';
                    
                case 'Linear'
                    nni_uni = interp1(tnn, nni, tnn_uni, 'linear')';
            end
            
            % Lomb method
            if(calc_lomb || strcmp(app.MetricsDropDown.Value,'Lomb'))
                for curr_win = 1:num_windows
                    curr_win_idx = (tnn >= t_win * (curr_win-1)) & (tnn < t_win * curr_win);
            
                    nni_win = nni(curr_win_idx);
                    tnn_win = tnn(curr_win_idx);
                    nni_win = nni_win - mean(nni_win);
                    
                    n_win = length(nni_win);
                    windw = win_func(n_win);
                    nni_win = nni_win .* windw;
            
                    % Check Nyquist criterion
                    min_samples_nyquist = ceil(2*f_max*t_win);
                    if (n_win < min_samples_nyquist)
                        warning('Nyquist criterion not met for lomb periodogram in window %d (only %d of %d samples)',...
                                curr_win, n_win, min_samples_nyquist);
                    end
            
                    [pxx_lomb_win, ~] = plomb(nni_win, tnn_win, f_axis);
                    pxx_lomb_win = pxx_lomb_win * (1/mean(windw)); % gain correction
                    pxx_lomb = pxx_lomb + pxx_lomb_win;
                end
                % Average
                pxx_lomb = pxx_lomb ./ num_windows;
            end
            
            % AR Method
            if (calc_ar || strcmp(app.MetricsDropDown.Value,'AR'))
                for curr_win = 1:num_windows_uni
                    curr_win_idx = ((curr_win - 1) * n_win_uni + 1) : (curr_win * n_win_uni);
                    nni_win = nni_uni(curr_win_idx);
                    nni_win = nni_win - mean(nni_win);
            
                    % AR periodogram
                    [pxx_ar_win, ~] = pyulear(nni_win, ar_order, f_axis, fs_uni);
                    pxx_ar = pxx_ar + pxx_ar_win;
                end
                % Average
                pxx_ar = pxx_ar ./ num_windows_uni;
            end

            % Welch Method
            if (calc_welch || strcmp(app.MetricsDropDown.Value,'Welch'))
                windw = win_func(n_win_uni);
                welch_overlap_samples = floor(n_win_uni * welch_overlap / 100);
                [pxx_welch, ~] = pwelch(nni_uni, windw, welch_overlap_samples, f_axis, fs_uni);
                pxx_welch  = pxx_welch * (1/mean(windw)); % gain correction
            end

            % FFT method
            if (calc_fft || strcmp(app.MetricsDropDown.Value,'FFT'))
                windw = win_func(n_win_uni);
                for curr_win = 1:num_windows_uni
                    curr_win_idx = ((curr_win - 1) * n_win_uni + 1) : (curr_win * n_win_uni);
                    nni_win = nni_uni(curr_win_idx);
                    nni_win = nni_win - mean(nni_win);
            
                    % FFT periodogram
                    [pxx_fft_win, ~] = periodogram(nni_win, windw, f_axis, fs_uni);
                    pxx_fft_win = pxx_fft_win * (1/mean(windw)); % gain correction
                    pxx_fft = pxx_fft + pxx_fft_win;
                end
                % Average
                pxx_fft = pxx_fft ./ num_windows_uni;
            end
            
            % Calculate metrics
            switch app.MetricsDropDown.Value
                case 'Lomb'
                    pxx = pxx_lomb;
                    app.SpectrummetricsPanel.Title = 'Spectrum metrics (Lomb)';
                    
                case 'AR'
                    pxx = pxx_ar;
                    app.SpectrummetricsPanel.Title = 'Spectrum metrics (AR)';
                    
                case 'Welch'
                    pxx = pxx_welch;
                    app.SpectrummetricsPanel.Title = 'Spectrum metrics (Welch)';
                    
                case 'FFT'
                    pxx = pxx_fft;
                    app.SpectrummetricsPanel.Title = 'Spectrum metrics (FFT)';
                    
                otherwise % if some error occurs
                    pxx = pxx_lomb;
                    app.SpectrummetricsPanel.Title = 'Spectrum metrics (Lomb)';
            end
            
            total_band = [VLF_band(1), HF_band(2)];
            % Absolute power in each band
            total_power = get_freqband_power(pxx, f_axis, total_band) * 1e6;
            app.vlf_power = get_freqband_power(pxx, f_axis, VLF_band) * 1e6;
            app.lf_power = get_freqband_power(pxx, f_axis, LF_band) * 1e6;
            app.hf_power  = get_freqband_power(pxx, f_axis, HF_band) * 1e6;
            
            % Relative power
            app.vlf_rel = 100 * app.vlf_power / total_power;
            app.lf_rel = 100 * app.lf_power / total_power;
            app.hf_rel = 100 * app.hf_power / total_power;
            
            % Normalized power (without considering VLF)
            total_power_noVLF = get_freqband_power(pxx, f_axis, [LF_band(1), HF_band(2)]) * 1e6;
            app.lf_norm = 100 * app.lf_power / total_power_noVLF;
            app.hf_norm = 100 * app.hf_power / total_power_noVLF;
            
            % Ratio LF/HF
            app.lf_to_hf  = app.lf_power  / app.hf_power;
            app.lf_to_hf

            % Find peaks in spectrum
            [~, lf_idx1] = min(abs(f_axis - LF_band(1)));
            [~, lf_idx2] = min(abs(f_axis - LF_band(2)));
            [~, hf_idx2] = min(abs(f_axis - HF_band(2)));
            [lf_peak_amp, lf_peak_idx] = max(pxx(lf_idx1:lf_idx2));
            [hf_peak_amp, hf_peak_idx] = max(pxx(lf_idx2:hf_idx2));
            app.lf_peak_freq = f_axis(lf_peak_idx + lf_idx1);
            app.hf_peak_freq = f_axis(hf_peak_idx + lf_idx2);
            
            % update fields
            app.VLFPowerEditField.Value = [num2str(round(app.vlf_power,1)), ' ms^2'];
            app.LFPowerEditField.Value = [num2str(round(app.lf_power,1)), ' ms^2'];
            app.HFPowerEditField.Value = [num2str(round(app.hf_power,1)), ' ms^2'];
            app.VLFEditField.Value = [num2str(round(app.vlf_rel,1)), '%'];
            app.LFEditField.Value = [num2str(round(app.lf_rel,1)), '%'];
            app.HFEditField.Value = [num2str(round(app.hf_rel,1)), '%'];
            app.LFnormEditField.Value = [num2str(round(app.lf_norm,1)), '%'];
            app.HFnormEditField.Value = [num2str(round(app.hf_norm,1)), '%'];
            app.LFHFEditField.Value = [num2str(app.lf_to_hf), ''];
            app.PeakLFEditField.Value = [num2str(round(app.lf_peak_freq,3)), ' Hz'];
            app.PeakHFEditField.Value = [num2str(round(app.hf_peak_freq,3)), ' Hz'];
            
            
            % PLOT
            cla(app.UIAxes6);
            hold(app.UIAxes6, 'on');
            
            legend_text = {};
            legend_handles = [];
            if(app.LombCheckBox.Value)
                p_lomb = plot(app.UIAxes6, f_axis, pxx_lomb, 'Color','red', 'LineWidth', 1.5);
                legend_text{end+1} = sprintf('Lomb (t_{win}=%.1fm, n=%d)', t_win/60, num_windows);
                legend_handles(end+1) = p_lomb;
            end
            
            if(app.ARCheckBox.Value)
                p_ar = plot(app.UIAxes6, f_axis, pxx_ar, 'Color','magenta', 'LineWidth', 1.5);
                legend_text{end+1} = sprintf('AR (order=%d)', ar_order);
                legend_handles(end+1) = p_ar;
            end
            
            if(app.WelchCheckBox.Value)
                p_welch = plot(app.UIAxes6, f_axis, pxx_welch, 'Color','blue', 'LineWidth', 1.5);
                legend_text{end+1} = sprintf('Welch (%d%% ovl.)', welch_overlap);
                legend_handles(end+1) = p_welch;
            end
            
            if(app.FFTCheckBox.Value)
                p_fft = plot(app.UIAxes6, f_axis, pxx_fft, 'Color',[0.4,0.1,0], 'LineWidth', 1.5);
                legend_text{end+1} = sprintf('FFT (t_{win}=%.1fm, n=%d)', t_win/60, num_windows);
                legend_handles(end+1) = p_fft;
            end
            
            
            if(app.LogscaleCheckBox.Value)
                set(app.UIAxes6, 'YScale', 'log');
            else
                set(app.UIAxes6, 'YScale', 'linear');
            end
            
            xlim(app.UIAxes6, [0, f_max * 1.01]);
            ylim(app.UIAxes6, 'auto');
            
            % draw freq. band limit lines
            y_range = ylim(app.UIAxes6)';
            line(app.UIAxes6, [VLF_band(2)*ones(2,1), LF_band(2)*ones(2,1), HF_band(2)*ones(2,1)], [y_range, y_range, y_range], 'Color','black', 'LineWidth', 2, 'LineStyle', '--');
            
            % name frequency bands
            text(app.UIAxes6, VLF_band(1), y_range(2) * 0.9, ' VLF');
            text(app.UIAxes6, LF_band(1), y_range(2) * 0.9,  ' LF');
            text(app.UIAxes6, HF_band(1), y_range(2) * 0.9,  ' HF');
            
            hp1 = plot(app.UIAxes6, app.lf_peak_freq, lf_peak_amp, 'kv', 'MarkerSize', 8, 'MarkerFaceColor', 'blue');
            legend_text{end+1} = [num2str(round(app.lf_peak_freq,3)), 'Hz'];
            legend_handles(end+1) = hp1;
            
            hp2 = plot(app.UIAxes6, app.hf_peak_freq, hf_peak_amp, 'kv', 'MarkerSize', 8, 'MarkerFaceColor', 'red');
            legend_text{end+1} = [num2str(round(app.hf_peak_freq,3)), 'Hz'];
            legend_handles(end+1) = hp2;
            
            grid(app.UIAxes6, 'on');
            axis(app.UIAxes6, 'tight');
            legend(app.UIAxes6, legend_handles, legend_text);
            title(app.UIAxes6, 'Spectrum of interval time series');
            xlabel(app.UIAxes6, 'Frequency (Hz)');
            ylabel(app.UIAxes6, 'Power density (ms^2/Hz)');
            
            close(d);
        end
    
        function updatePoincare(app)
            d = uiprogressdlg(app.HRVMetricsUIFigure, 'Title', 'Please wait...', 'Message', 'Calculating Poincaré plot...', 'Indeterminate', 'on');
            
            lag = floor(app.LagEditField.Value);
            plotOrder = str2double(app.PlotorderDropDown.Value);
            showGuideLines = app.ShowellipseaxisCheckBox.Value;
            
            cla(app.UIAxes7);
            
            if(plotOrder == 1)
                x = app.peak_to_peak(1:end-lag);
                y = app.peak_to_peak(lag+1:end);
                plot(app.UIAxes7, x, y, 'k.');
                view(app.UIAxes7, 2);
                title(app.UIAxes7, 'Poincaré plot');
                xlabel(app.UIAxes7, 'RR_n (s)');
                ylabel(app.UIAxes7, ['RR_{n+',num2str(lag),'} (s)']);
                
                % calculate SD1 and SD2
                P = [x; y];
                th = pi/4; % rotate by 45º
                B1 = [cos(th), -sin(th); sin(th), cos(th)]; % rotation matrix
                B2 = B1';   % this simplification works (in reality B2 = inv(B1)
                P1 = B1 * P;
                P2 = B2 * P;
                app.metric_NL_SD1 = std(P1(1,:)); % only use the x-coordinates (1)
                app.metric_NL_SD2 = std(P2(1,:));
                
                app.SD1EditField.Value = [num2str(round(app.metric_NL_SD1*1000)), ' ms'];
                app.SD2EditField.Value = [num2str(round(app.metric_NL_SD2*1000)), ' ms'];
                app.SD3EditField.Value = '';
                app.SD3EditField.Enable = false;
                
                if(showGuideLines)
                    % plot major and minor axis
                    factor1 = 3;
                    M = mean(app.peak_to_peak);
                    S1_axis = [(M + factor1*app.metric_NL_SD1), (M - factor1*app.metric_NL_SD1)];
                    S2_axis = [(M - factor1*app.metric_NL_SD2), (M + factor1*app.metric_NL_SD2)];
                    
                    hold(app.UIAxes7, 'on');
                    plot(app.UIAxes7, flip(S1_axis), S1_axis, 'Color', 'green');
                    plot(app.UIAxes7, S2_axis, S2_axis, 'Color', 'blue');
                    text(app.UIAxes7, S1_axis(2), S1_axis(1),'SD1', 'FontWeight', 'bold');
                    text(app.UIAxes7, S2_axis(2), S2_axis(2),'SD2', 'FontWeight', 'bold');
                    
                    % plot ellipse
                    factor2 = 2;
                    x1 = M - factor2*app.metric_NL_SD2;
                    y1 = x1;
                    x2 = M + factor2*app.metric_NL_SD2;
                    y2 = x2;
                    a = 0.5 * norm([x1,y1] - [x2,y2]);
                    b = factor2 * app.metric_NL_SD1;
                    t = linspace(0,2*pi);
                    X = a*cos(t);
                    Y = b*sin(t);
                    w = atan2(y2-y1,x2-x1);
                    x = (x1+x2)/2 + X*cos(w) - Y*sin(w);
                    y = (y1+y2)/2 + X*sin(w) + Y*cos(w);
                    plot(app.UIAxes7, x,y,'r--');
                    
                    hold(app.UIAxes7, 'off');
                end
                
            elseif(plotOrder == 2)
                
                x = app.peak_to_peak(1:end-2*lag);
                y = app.peak_to_peak(lag+1:end-lag);
                z = app.peak_to_peak(2*lag+1:end);
                scatter3(app.UIAxes7, x, y, z, 'filled', 'MarkerEdgeColor', 'k', 'MarkerFaceColor', 'cyan');
                grid(app.UIAxes7, 'on');
                view(app.UIAxes7, 3);
                title(app.UIAxes7, 'Poincaré plot');
                xlabel(app.UIAxes7, 'RR_n (s)');
                ylabel(app.UIAxes7, ['RR_{n+',num2str(lag),'} (s)']);
                zlabel(app.UIAxes7, ['RR_{n+',num2str(2*lag),'} (s)']);
                
                % calculate SD1, SD2 and SD3
                P = [x; y; z];
                th = pi/4; % rotate by 45º
                Bx = [1, 0, 0; 0, cos(th), -sin(th); 0, sin(th), cos(th)]; % rotation matrix (3D)
                Bz = [ cos(th), -sin(th), 0; sin(th), cos(th), 0; 0, 0, 1];
                P = Bx \ P;
                P = Bz \ P;

                app.metric_NL_SD1 = std(P(1,:));
                app.metric_NL_SD2 = std(P(2,:));
                app.metric_NL_SD3 = std(P(3,:));
                
                app.SD3EditField.Enable = true;
                app.SD1EditField.Value = [num2str(round(app.metric_NL_SD1*1000)), ' ms'];
                app.SD2EditField.Value = [num2str(round(app.metric_NL_SD2*1000)), ' ms'];
                app.SD3EditField.Value = [num2str(round(app.metric_NL_SD3*1000)), ' ms'];
                
                if(showGuideLines)
                    % plot major and minor axis
                    factor1 = 3;
                    M = mean(app.peak_to_peak);
                    S1_axis = [(M - factor1*app.metric_NL_SD1), (M + factor1*app.metric_NL_SD1)];
                    S2_axis = [(M - factor1*app.metric_NL_SD2), (M + factor1*app.metric_NL_SD2)];
                    S3_axis = [(M - factor1*app.metric_NL_SD3), (M + factor1*app.metric_NL_SD3)];
                    
                    hold(app.UIAxes7, 'on');
                    plot3(app.UIAxes7, S1_axis, S1_axis, S1_axis, 'Color', 'green');
                    plot3(app.UIAxes7, S2_axis, flip(S2_axis), [M, M], 'Color', 'blue');
                    plot3(app.UIAxes7, S3_axis, S3_axis, flip(S3_axis), 'Color', 'red');
                    text(app.UIAxes7, S1_axis(2), S1_axis(2), S1_axis(2), 'SD1', 'FontWeight', 'bold');
                    text(app.UIAxes7, S2_axis(1), S2_axis(2), M, 'SD2', 'FontWeight', 'bold');
                    text(app.UIAxes7, S3_axis(1), S3_axis(1), S3_axis(2), 'SD3', 'FontWeight', 'bold');
                    
                    hold(app.UIAxes7, 'off');
                end
            end
            
            axis(app.UIAxes7, 'tight');
            
            close(d);
        end
        
        function updateAproxEntropy(app)
            
            d = uiprogressdlg(app.HRVMetricsUIFigure, 'Title', 'Please wait...', 'Message', 'Calculating Approximate Entropy...', 'Indeterminate', 'on');
            
            m1 = app.EmbeddingdimensionEditField.Value;
            r = app.TolerancerEditField.Value;
            N = length(app.peak_to_peak);
            
            phi = [0, 0];
            for m = [m1, m1 + 1]
                u = zeros(N-m+1, m);
                for i = 1:(N-m+1)
                    u(i,:) = app.peak_to_peak(i:(i+m-1));
                end
                
                C = zeros(1, N-m+1);
                for i = 1:(N-m+1)
                    ui = u(i,:);
                    for j = 1:(N-m+1)
                        C(i) =  C(i) + (max(abs(ui - u(j,:))) <= r);
                    end
                    C(i) = C(i) / (N-m+1);
                end
                
                phi(m - m1 + 1) = sum(log(C)) / (N-m+1);
            end
            app.metric_NL_aproxEntropy = -diff(phi);
            app.ApproximateEntropyEditField.Value = app.metric_NL_aproxEntropy;
            
            close(d);
        end


        function updateSampleEntropy(app)
            d = uiprogressdlg(app.HRVMetricsUIFigure, 'Title', 'Please wait...', 'Message', 'Calculating Sample Entropy...', 'Indeterminate', 'on');
            
            m1 = app.EmbeddingdimensionEditField.Value;
            r = app.TolerancerEditField.Value;
            N = length(app.peak_to_peak);
            
            phi = [0, 0];
            for m = [m1, m1 + 1]
                u = zeros(N-m+1, m);
                for i = 1:(N-m+1)
                    u(i,:) = app.peak_to_peak(i:(i+m-1));
                end
                
                C = zeros(1, N-m+1);
                for i = 1:(N-m+1)
                    ui = u(i,:);
                    for j = 1:(N-m+1)
                        if(i == j)
                            continue;   % in sample entropy these are never compared so not to introduce bias
                        end
                        C(i) =  C(i) + (max(abs(ui - u(j,:))) <= r);
                    end
                    C(i) = C(i) / (N-m);
                end
                
                phi(m - m1 + 1) = sum(C) / (N-m+1);
            end
            
            app.metric_NL_sampleEntropy = log(phi(1) / phi(2));
            app.SampleEntropyEditField.Value = app.metric_NL_sampleEntropy;
            
            close(d);
        end
    
        function updateMSE(app)
            d = uiprogressdlg(app.HRVMetricsUIFigure, 'Title', 'Please wait...', 'Message', 'Calculating MSE...', 'Indeterminate', 'on');
            
            maxScale = app.MaxscaleEditField.Value;
            app.metric_NL_MSE = MSE(app.peak_to_peak, 1, maxScale);
            cla(app.UIAxes10);
            hold(app.UIAxes10, 'on');
            plot(app.UIAxes10, 1:maxScale, app.metric_NL_MSE, 'k--');
            plot(app.UIAxes10, 1:maxScale, app.metric_NL_MSE, 'ko');
            title(app.UIAxes10, 'Multiscale entropy plot');
            ylabel(app.UIAxes10, 'Sample entropy');
            xlabel(app.UIAxes10, 'Scale factor');
            axis(app.UIAxes10 , 'tight');
            grid(app.UIAxes10, 'on');
            
            close(d);
        end
        
        function updateRecurr(app)
            d = uiprogressdlg(app.HRVMetricsUIFigure, 'Title', 'Please wait...', 'Message', 'Calculating Recurrence plot...', 'Indeterminate', 'on');
            
            m = app.EmbeddingdimensionEditField_3.Value;
            r = app.ThresholdrEditField.Value;
            lag = app.LagEditField_2.Value;
            N = length(app.peak_to_peak);
            
            u = zeros(N - (m-1)*lag, m);
            for i = 1:(N-(m-1)*lag)
                u(i,:) = app.peak_to_peak(i : lag : i + (m-1)*lag);
            end
            
            dim = N - (m-1)*lag;
            RP = false(dim, dim);
            for i = 1:dim
                for j = 1:dim
                    RP(i,j) =  norm(u(i,:) - u(j,:)) <= r;
                end
            end
            
            if(app.useSameInterval.Value)
                sample = max(round(app.startTime*app.Fs),1) : min(round(app.endTime*app.Fs), length(app.ecg));
            else
                sample = 1 : round(app.duration*app.Fs);
            end
            ecgN = length(sample);
            imagesc(app.UIAxes9, linspace(0, ecgN-1 , dim-1)./app.Fs/60, linspace(0, ecgN-1, dim-1)./app.Fs/60, RP);
            title(app.UIAxes9, 'Recurrence plot (RP)');
            xlabel(app.UIAxes9, 'Time (min)');
            ylabel(app.UIAxes9, 'Time (min)');
            set(app.UIAxes9, 'YDir', 'normal');
            axis(app.UIAxes9, 'tight');
            colormap(app.UIAxes9, gray);
            
            app.metric_NL_REC = sum(RP,'all') / (dim).^2;
            app.RecurrencerateRECEditField_2.Value = [num2str(round(app.metric_NL_REC*100)), ' %'];
            close(d);
        end
        
        function updateDFA(app)
            
            d = uiprogressdlg(app.HRVMetricsUIFigure, 'Title', 'Please wait...', 'Message', 'Calculating MSE...', 'Indeterminate', 'on');
            
            cla(app.UIAxes8);
            
            N = length(app.peak_to_peak);
            order = app.LeastSquareRegressionorderEditField.Value;
            breakpoint = app.BreakpointbeatsEditField.Value;
            min_s = app.MinimumsegmentsizeEditField.Value;
            max_s = app.MaximumsegmentsizeEditField.Value;
            num_s = app.NumberofsegmentsizesEditField.Value;
            
            y = cumsum(app.peak_to_peak - mean(app.peak_to_peak));
            
            if(app.SamplevectorinterpolationButtonGroup.SelectedObject == app.LinearButton)
                n = linspace(min_s, max_s, num_s);
            else
                n = logspace(log10(min_s), log10(max_s), num_s);
            end
            
            F = zeros(1, length(n));
            
            for n_idx = 1:length(n) % iterate through all possible segment sizes
                nn = n(n_idx);  % size of segments
                
                %calculate Least Square Line Fit
                yn = zeros(1,N);
                for i = 1:floor(N/nn)    % go through all segments
                    
                    if(i == floor(N/nn))     % if last group, make sure we get all elements
                        t = floor((i-1)*nn) + 1 : N;
                    else
                        t = floor((i-1)*nn) + 1 : floor(i*nn);
                    end
                    
                    if(order == 1)  % much faster method  
                        yy = y(t)';
                        X = [ones(length(t),1), t'];
                        b = X\yy;
                        yn(t) = X*b;
                    else
                        yn(t) = polyval(polyfit(t, y(t),order), t);
                    end
                end

                % calculate F
                F(n_idx) = rms(y - yn);     % root mean square (rms)
                
                if(n_idx == 0)
                    plot(app.UIAxes8, yn, 'Color', 'black');
                    hold(app.UIAxes8,'on');
                    plot(app.UIAxes8, y, 'Color', 'red');
                    hold(app.UIAxes8,'off');
                end
            end
            
            plot(app.UIAxes8, log10(n), log10(F), 'k.');
            
            %set(app.UIAxes8, 'YScale', 'log');
            %set(app.UIAxes8, 'XScale', 'log');
            title(app.UIAxes8, 'DFA plot of RR intervals');
            xlabel(app.UIAxes8, 'log_{10} n (Segment length)');
            ylabel(app.UIAxes8, 'log_{10} F(n) = log_{10} RMS(y - y_n)');
            set(app.UIAxes8, 'YDir', 'normal');
            
            % plot alpha1 and alpha2 regression lines
            [~, index] = min(abs(n - breakpoint));  % look for closest index
            %n = floor(n);
            
            t1 = n(1:index);
            F1 = log10(F(1:length(t1)))';
            X1 = [ones(length(t1),1), log10(t1)'];
            b1 = X1\F1;
            y1 = X1 * b1;
            
            t2 = n((index+1):end);
            F2 = log10(F((length(t1)+1):end))';
            X2 = [ones(length(t2),1), log10(t2)'];
            b2 = X2\F2;
            y2 = X2 * b2;
            
            app.Alpha1EditField.Value = num2str(b1(2));
            app.Alpha2EditField.Value = num2str(b2(2));
            
            if(app.SamplevectorinterpolationButtonGroup.SelectedObject == app.LinearButton)
                h1 = (log10(t1(1))+log10(t1(end)))/2;
                h2 = (log10(t2(1))+log10(t2(end)))/2;
            else
                h1 = log10(t1(end/2));
                h2 = log10(t2(end/2));
            end
            
            hold(app.UIAxes8, 'on');
            plot(app.UIAxes8, log10(t1), y1, 'Color', 'red', 'LineWidth', 2);
            text(app.UIAxes8, h1, y1(end/2) + (y1(end)-y1(1))/4, '\alpha 1', 'FontWeight', 'bold');
            plot(app.UIAxes8, log10(t2), y2, 'Color', 'green', 'LineWidth', 2)
            text(app.UIAxes8, h2, y2(end/2) + (y2(end)-y2(1))/4, '\alpha 2', 'FontWeight', 'bold');
            hold(app.UIAxes8, 'off');
            
            axis(app.UIAxes8, 'tight');
            zoom(app.UIAxes8, 'on');
            
            close(d);
        end
    
        function nonLinearTabChanged(app)
            switch app.TabGroup3.SelectedTab
                case app.PoincarplotTab
                    if(~app.poincare_tab_done)
                        updatePoincare(app);
                        app.poincare_tab_done = true;
                    end
                    
                    
                case app.EntropyTab
                    if(~app.entropy_tab_done)
                        std_rr = round(0.2*std(app.peak_to_peak), 6);
                        app.TolerancerEditField.Value = std_rr;
                        app.TolerancerEditField_2.Value = std_rr;
                        
                        updateAproxEntropy(app);
                        updateSampleEntropy(app);
                        updateMSE(app);
                        app.entropy_tab_done = true;
                    end
                    
                case app.DFATab
                    if(~app.DFA_tab_done)
                        updateDFA(app);
                        app.DFA_tab_done = true;
                    end
                    
                case app.RecurrenceplotTab
                    if(~app.recurrence_tab_done)
                        app.ThresholdrEditField.Value = std(app.peak_to_peak) * sqrt(app.EmbeddingdimensionEditField_3.Value);
                        updateRecurr(app);
                        app.recurrence_tab_done = true;
                    end
                    
            end
        end
        
        function loadECG(app)
            
            % Get info from ECG
            app.ecg_all = app.ecg_data.ecg;
            app.numberOfChannels = size(app.ecg_all, 1);
            app.selectedChannel = str2double(app.SelectedchannelDropDown.Value);
            app.ecg = app.ecg_data.ecg(app.selectedChannel,:);
            ecg_size = length(app.ecg);
            app.Fs = app.ecg_data.Fs;
            app.duration = ecg_size/app.Fs;
            
            app.ecg_loaded = true;
            
            % Adjust time setters
            app.startTime = 0;
            app.endTime = app.duration;
            
            % Plot
            set(app.UIAxes,'YDir','Normal');
            axis(app.UIAxes, 'on');
            plot(app.UIAxes, (1:length(app.ecg))/app.Fs, app.ecg);
            axis(app.UIAxes, 'tight');
            title(app.UIAxes, 'Raw ECG');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude (a.u.)');
            
            app.peak_det_done = false;
            
            app.MeanButton.Enable = true;
            app.MedianButton.Enable = true;
            app.MovingmeanButton.Enable = true;
            app.MovingmedianButton.Enable = true;
            app.ComparetoprevButton.Enable = true;
            app.WindowsizesEditField.Enable = true;
            
        end
    
        function result = get_H_M_S_time(~, sec)
            if(sec > 86400)
                result = "> 24h";
            else
                hour = floor(mod(sec/3600, 24));
                min = floor(mod(sec/60, 60));
                sec = floor(mod(sec, 60));
                
                if(min < 10)
                    m = ['0',num2str(min)];
                else
                    m = num2str(min);
                end
                if(sec < 10)
                    s = ['0',num2str(sec)];
                else
                    s = num2str(sec);
                end
                result = [num2str(hour),':',num2str(m),':',num2str(s)];
            end
        end
    
        function reset_metrics(app)
            % app metrics
            % Geometric
            app.metric_Geom_HRVTriangularIndex = 0;
            app.metric_Geom_TINN = 0;
            app.metric_Geom_LogaritmicIndex = 0;
            % Statistical (time series)
            app.metric_TS_SDNN = 0;
            app.metric_TS_SDANN = 0;
            app.metric_TS_RMSSD = 0;
            app.metric_TS_SDNNindex = 0;
            app.metric_TS_SDSD = 0;
            app.metric_TS_NN50 = 0;
            app.metric_TS_NN50i = 0;
            app.metric_TS_NN50d = 0;
            app.metric_TS_pNN50 = 0;
            % Frequency metrics
            app.vlf_power = 0;
            app.lf_power = 0;
            app.hf_power = 0;
            app.vlf_rel = 0;
            app.lf_rel = 0;
            app.hf_rel = 0;
            app.lf_norm = 0;
            app.hf_norm = 0;
            app.lf_to_hf = 0;
            app.lf_peak_freq = 0;
            app.hf_peak_freq = 0;
            % Non linear metrics
            % Poincaré
            app.metric_NL_SD1 = 0;
            app.metric_NL_SD2 = 0;
            app.metric_NL_SD3 = 0;
            %Entropy
            app.metric_NL_aproxEntropy = 0;
            app.metric_NL_sampleEntropy = 0;
            app.metric_NL_MSE = 0;
            % DFA
            app.metric_NL_A1 = 0;
            app.metric_NL_A2 = 0;
            % Recurrence
            app.metric_NL_REC = 0;
            
        end
    
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = false;
            
            app.peak_tab_done = false;
            app.tachogram_tab_done = false;
            app.geom_tab_done = false;
            app.timeseries_tab_done = false;
            app.freq_tab_done = false;
            app.poincare_tab_done = false;
            app.entropy_tab_done = false;
            app.DFA_tab_done = false;
            app.recurrence_tab_done = false;
            
            app.MeanButton.Enable = true;
            app.MedianButton.Enable = true;
            app.MovingmeanButton.Enable = true;
            app.MovingmedianButton.Enable = true;
            app.ComparetoprevButton.Enable = true;
            app.WindowsizesEditField.Enable = true;
            
            selectedButton = app.MethodButtonGroup.SelectedObject;
                
            app.ComparetoprevthresholdEditField.Enable = false;
            app.WindowsizesEditField.Enable = false;
                
            switch selectedButton
                case app.MeanButton
                    app.removeOutliersMethod = 'mean';
            
                case app.MedianButton
                    app.removeOutliersMethod = 'median';
                
                case app.MovingmeanButton
                    app.removeOutliersMethod = 'movmean';
                    app.WindowsizesEditField.Enable = true;
                
                case app.MovingmedianButton
                    app.removeOutliersMethod = 'movmedian';
                    app.WindowsizesEditField.Enable = true;
                    
                case app.ComparetoprevButton
                    app.removeOutliersMethod = 'compare';
                    app.ComparetoprevthresholdEditField.Enable = true;
            end
        
            zoom(app.UIAxes, 'on');
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            
            % Get file
            
            % FILE FORMAT:
            % The .mat file must contain:
            % Fs (sampling frequency)
            % ecg (ecg signal):
            %   - Each channel in separate rows (samples along different columns)
            %   size(ecg) = [#channels, recordSizeInSamples]
            [file,path] = uigetfile('*.mat');
            
            app.HRVMetricsUIFigure.Visible = 'off';
            app.HRVMetricsUIFigure.Visible = 'on';
            
            if(file == 0)   % if Cancel is pressed
                return;
            end
            
            d = uiprogressdlg(app.HRVMetricsUIFigure, 'Title', 'Please wait...', 'Message', 'Loading ECG record...', 'Indeterminate', 'on');
            
            
            app.ecg_data = load([path,file],'ecg','Fs');
            
            loadECG(app);
            
            % File Info Panel
            app.FilenameEditField.Value = file;
            app.SamplingfrequencyEditField.Value = [num2str(app.Fs), ' Hz'];
            app.NumberofchannelsEditField.Value = num2str(app.numberOfChannels);
            items = cell(1,app.numberOfChannels);
            for i = 1:app.numberOfChannels
                items{i} = num2str(i);
            end
            app.SelectedchannelDropDown.Items = items;
            app.DurationEditField.Value = [get_H_M_S_time(app, app.duration), ' (h:m:s)'];
            app.NumberofsamplesEditField.Value = num2str(app.duration * app.Fs);
            
            % Enable elements
            app.UIAxes.Visible = true;
            app.CleardataButton.Enable = true;
            app.PlotcontrolPanel_2.Visible = true;
            
            close(d);
        end

        % Button pushed function: CleardataButton
        function CleardataButtonPushed(app, event)
            
            % Erase data / reset values
            app.Fs = 0;
            app.ecg = [];
            app.startTime = 0;
            app.endTime = 0;
            app.duration = 0;
            app.peaks = [];
            app.peak_locs = [];
            app.peak_det_done = false;
            app.ecg_loaded = false;
            
            % File Info Panel
            app.FilenameEditField.Value = '';
            app.SamplingfrequencyEditField.Value = '';
            app.NumberofchannelsEditField.Value = '';
            app.SelectedchannelDropDown.Items = {'1'};
            app.DurationEditField.Value = '';
            app.NumberofsamplesEditField.Value = '';
            
            % Disable elements
            app.UIAxes.Visible = false;
            app.CleardataButton.Enable = false;
            app.PlotcontrolPanel_2.Visible = false;
            
            % Clear plot
            axis(app.UIAxes, 'off');
            title(app.UIAxes, '');
            cla(app.UIAxes);
            
            % Clear all plots from other tabs
            cla(app.UIAxes2);
            cla(app.UIAxes4);
            cla(app.UIAxes4_2);
            cla(app.GeometryAxis);
            cla(app.GeometryAxis2);
            cla(app.UIAxes6);
            cla(app.UIAxes7);
            cla(app.UIAxes8);
            cla(app.UIAxes9);
            cla(app.UIAxes10);
            
            % Reset all metrics
            reset_metrics(app);
            
            % Reset control variables
            app.peak_det_done = false;
            app.peak_tab_done = false;
            app.tachogram_tab_done = false;
            app.geom_tab_done = false;
            app.timeseries_tab_done = false;
            app.freq_tab_done = false;
            app.poincare_tab_done = false;
            app.entropy_tab_done = false;
            app.DFA_tab_done = false;
            app.recurrence_tab_done = false;
            
            app.MeanButton.Enable = true;
            app.MedianButton.Enable = true;
            app.MovingmeanButton.Enable = true;
            app.MovingmedianButton.Enable = true;
            app.ComparetoprevButton.Enable = true;
            app.WindowsizesEditField.Enable = true;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if(selectedTab ~= app.LoadTab && selectedTab ~= app.ReportTab)
                if(~app.ecg_loaded)
                    app.TabGroup.SelectedTab = app.LoadTab;
                    uialert(app.HRVMetricsUIFigure, {'No ECG loaded yet!','Use "Load file" to load an ECG record.'}, 'Error!');
                        return;
                end
                if(~ app.peak_det_done)
                    getPeaks(app);
                end
                
            end
            
            switch selectedTab
                case app.LoadTab

                case app.PeakdetTab
                    if(~app.peak_tab_done)
                        runPeakDetection(app);
                        app.peak_tab_done = true;
                    end
                
                case app.TachogramTab
                    if(~app.tachogram_tab_done)
                        updateTachogram(app);
                        app.tachogram_tab_done = true;
                    end
                    
                case app.TimedomainTab
                    if(~app.timeseries_tab_done)
                        updateTimeSeries(app);
                        updateGeometry(app);
                        app.timeseries_tab_done = true;
                        app.geom_tab_done = true;
                    end
                    
                case app.FrequencydomainTab
                    if(~app.freq_tab_done)
                        updateFrequencyDomain(app);
                        app.freq_tab_done = true;
                    end
                
                case app.NonlinearanalysisTab
                    nonLinearTabChanged(app);
                    
                case app.ReportTab
                    app.PathtosaveEditField.Value = pwd;
            end
            
        end

        % Value changed function: ShowtriangularinterpolationCheckBox
        function ShowtriangularinterpolationCheckBoxValueChanged(app, event)
            updateGeometry(app);
        end

        % Value changed function: ShowexponencialinterpolationCheckBox
        function ShowexponencialinterpolationCheckBoxValueChanged(app, event)
            updateGeometry(app);
        end

        % Button pushed function: UpdateselectionButton
        function UpdateselectionButtonPushed(app, event)
            limits = app.UIAxes.XLim;
            
            if(app.startTime ~= limits(1) || app.endTime ~= limits(2))
                app.peak_det_done = false;
                app.peak_tab_done = false;
                app.tachogram_tab_done = false;
                app.geom_tab_done = false;
                app.timeseries_tab_done = false;
                app.freq_tab_done = false;
                app.DFA_tab_done = false;
                app.recurrence_tab_done = false;
                app.poincare_tab_done = false;
                app.entropy_tab_done = false;
                
                app.startTime = limits(1);
                app.endTime = limits(2);
                app.DurationLabel_2.Text = ['Duration: ', get_H_M_S_time(app, diff(limits))];
                app.SamplesLabel.Text = ['Samples: ', num2str(floor(diff(limits) * app.Fs))];
            end
        end

        % Value changed function: RemoveoutliersCheckBox
        function RemoveoutliersCheckBoxValueChanged(app, event)
            value = app.RemoveoutliersCheckBox.Value;
            if(value)
                app.MeanButton.Enable = true;
                app.MedianButton.Enable = true;
                app.MovingmeanButton.Enable = true;
                app.MovingmedianButton.Enable = true;
                app.ComparetoprevButton.Enable = true;
                app.NumberofoutliersEditField.Enable = true;
                
                app.ComparetoprevthresholdEditField.Enable = false;
                app.WindowsizesEditField.Enable = false;
                
                switch app.MethodButtonGroup.SelectedObject
                case app.MeanButton
                    app.removeOutliersMethod = 'mean';
            
                case app.MedianButton
                    app.removeOutliersMethod = 'median';
                
                case app.MovingmeanButton
                    app.removeOutliersMethod = 'movmean';
                    app.WindowsizesEditField.Enable = true;
                
                case app.MovingmedianButton
                    app.removeOutliersMethod = 'movmedian';
                    app.WindowsizesEditField.Enable = true;
                    
                case app.ComparetoprevButton
                    app.removeOutliersMethod = 'compare';
                    app.ComparetoprevthresholdEditField.Enable = true;
                end
                
                updateTachogram(app);
                
            else
                app.MeanButton.Enable = false;
                app.MedianButton.Enable = false;
                app.MovingmeanButton.Enable = false;
                app.MovingmedianButton.Enable = false;
                app.WindowsizesEditField.Enable = false;
                app.ComparetoprevButton.Enable = false;
                app.NumberofoutliersEditField.Enable = false;
                app.ComparetoprevthresholdEditField.Enable = false;
                
                updateTachogram(app);
            end
        end

        % Selection changed function: MethodButtonGroup
        function MethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.MethodButtonGroup.SelectedObject;
            
            app.ComparetoprevthresholdEditField.Enable = false;
            app.WindowsizesEditField.Enable = false;
                
            switch selectedButton
                case app.MeanButton
                    app.removeOutliersMethod = 'mean';
            
                case app.MedianButton
                    app.removeOutliersMethod = 'median';
                
                case app.MovingmeanButton
                    app.removeOutliersMethod = 'movmean';
                    app.WindowsizesEditField.Enable = true;
                
                case app.MovingmedianButton
                    app.removeOutliersMethod = 'movmedian';
                    app.WindowsizesEditField.Enable = true;
                    
                case app.ComparetoprevButton
                    app.removeOutliersMethod = 'compare';
                    app.ComparetoprevthresholdEditField.Enable = true;
            end
            
            updateTachogram(app);
        end

        % Value changed function: WindowsizesEditField
        function WindowsizesEditFieldValueChanged(app, event)
            updateTachogram(app);            
        end

        % Value changed function: ComparetoprevthresholdEditField
        function ComparetoprevthresholdEditFieldValueChanged(app, event)
            updateTachogram(app);
            
        end

        % Value changed function: useSameInterval
        function useSameIntervalValueChanged(app, event)
            value = app.useSameInterval.Value;
            
            if(value)
                app.UpdateselectionButton.Enable = true;
                app.DurationLabel_2.Enable = true;
                app.SamplesLabel.Enable = true;
            else
                app.UpdateselectionButton.Enable = false;
                app.DurationLabel_2.Enable = false;
                app.SamplesLabel.Enable = false;
            end
            
        end

        % Callback function
        function SelectedchannelEditFieldValueChanged(app, event)
            value = app.SelectedchannelEditField.Value;
            
            if(floor(value) == value && value <= app.numberOfChannels)
                loadECG(app);
            else
                app.SelectedchannelEditField.Value = app.selectedChannel;
            end
        end

        % Selection change function: TabGroup3
        function TabGroup3SelectionChanged(app, event)
            nonLinearTabChanged(app);
        end

        % Value changed function: LeastSquareRegressionorderEditField
        function LeastSquareRegressionorderEditFieldValueChanged(app, event)
            value = app.LeastSquareRegressionorderEditField.Value;
            
            if(floor(value) == value && value > 0)
                updateDFA(app);
            else
                
            end

        end

        % Value changed function: BreakpointbeatsEditField
        function BreakpointbeatsEditFieldValueChanged(app, event)
            value = app.BreakpointbeatsEditField.Value;
            
            if(floor(value) == value && value > 0)
                updateDFA(app);
            else
                
            end
        end

        % Selection changed function: 
        % SamplevectorinterpolationButtonGroup
        function SamplevectorinterpolationButtonGroupSelectionChanged(app, event)
            updateDFA(app);
        end

        % Value changed function: MinimumsegmentsizeEditField
        function MinimumsegmentsizeEditFieldValueChanged(app, event)
            updateDFA(app);
        end

        % Value changed function: MaximumsegmentsizeEditField
        function MaximumsegmentsizeEditFieldValueChanged(app, event)
            updateDFA(app);
        end

        % Value changed function: NumberofsegmentsizesEditField
        function NumberofsegmentsizesEditFieldValueChanged(app, event)
            updateDFA(app);
        end

        % Value changed function: SelectedchannelDropDown
        function SelectedchannelDropDownValueChanged(app, event)
            loadECG(app);
        end

        % Value changed function: ShowellipseaxisCheckBox
        function ShowellipseaxisCheckBoxValueChanged(app, event)
            updatePoincare(app);
        end

        % Value changed function: LagEditField
        function LagEditFieldValueChanged(app, event)
            updatePoincare(app);
        end

        % Value changed function: PlotorderDropDown
        function PlotorderDropDownValueChanged(app, event)
            updatePoincare(app);
            if(str2double(app.PlotorderDropDown.Value) == 2)
                app.ViewXYButton.Enable = true;
                app.ViewXZButton.Enable = true;
                app.ViewZYButton.Enable = true;
                app.IsoviewButton.Enable = true;
            else
                app.ViewXYButton.Enable = false;
                app.ViewXZButton.Enable = false;
                app.ViewZYButton.Enable = false;
                app.IsoviewButton.Enable = false;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.TolerancerEditField.Value = round(0.2 * std(app.peak_to_peak), 6);
            app.EmbeddingdimensionEditField.Value = 2;
            updateAproxEntropy(app);
        end

        % Value changed function: EmbeddingdimensionEditField
        function EmbeddingdimensionEditFieldValueChanged(app, event)
            value = app.EmbeddingdimensionEditField.Value;
            if(floor(value) == value && value > 0)
                updateAproxEntropy(app);
            end
        end

        % Value changed function: TolerancerEditField
        function TolerancerEditFieldValueChanged(app, event)
            value = app.TolerancerEditField.Value;
            if(value > 0)
                updateAproxEntropy(app);
            end
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.TolerancerEditField_2.Value = round(0.2 * std(app.peak_to_peak), 6);
            app.EmbeddingdimensionEditField_2.Value = 2;
            updateSampleEntropy(app);
        end

        % Value changed function: TolerancerEditField_2
        function TolerancerEditField_2ValueChanged(app, event)
            value = app.TolerancerEditField_2.Value;
            if(value > 0)
                updateSampleEntropy(app);
            end
        end

        % Value changed function: EmbeddingdimensionEditField_2
        function EmbeddingdimensionEditField_2ValueChanged(app, event)
            value = app.EmbeddingdimensionEditField_2.Value;
            if(floor(value) == value && value > 0)
                updateSampleEntropy(app);
            end
        end

        % Value changed function: ThresholdrEditField
        function ThresholdrEditFieldValueChanged(app, event)
            value = app.ThresholdrEditField.Value;
            if(value > 0)
                updateRecurr(app);
            end
        end

        % Value changed function: LagEditField_2
        function LagEditField_2ValueChanged(app, event)
            value = app.LagEditField_2.Value;
            if(floor(value) == value && value > 0)
                updateRecurr(app);
            end
        end

        % Value changed function: EmbeddingdimensionEditField_3
        function EmbeddingdimensionEditField_3ValueChanged(app, event)
            value = app.EmbeddingdimensionEditField_3.Value;
            if(floor(value) == value && value > 0)
                app.ThresholdrEditField.Value = std(app.peak_to_peak) * sqrt(value);
                updateRecurr(app);
            end
        end

        % Button pushed function: ViewXYButton
        function ViewXYButtonPushed(app, event)
            view(app.UIAxes7, [0,0,1]);
        end

        % Button pushed function: ViewXZButton
        function ViewXZButtonPushed(app, event)
            view(app.UIAxes7, [0,1,0]);
        end

        % Button pushed function: ViewZYButton
        function ViewZYButtonPushed(app, event)
            view(app.UIAxes7, [1,0,0]);
        end

        % Button pushed function: IsoviewButton
        function IsoviewButtonPushed(app, event)
            view(app.UIAxes7, [-1,-1,1]);
        end

        % Callback function
        function TrendremovalDropDownValueChanged(app, event)
            if(strcmp(app.TrendremovalDropDown.Value, "Mov. avr."))
                app.MovwindowsampEditField.Enable = true;
            else
                app.MovwindowsampEditField.Enable = false;
            end
            
            updateFrequencyDomain(app);
        end

        % Callback function
        function ResamplefreqHzEditFieldValueChanged(app, event)
            updateFrequencyDomain(app);
        end

        % Value changed function: ResampleinterpDropDown
        function ResampleinterpDropDownValueChanged(app, event)
            updateFrequencyDomain(app);
        end

        % Value changed function: MetricsDropDown
        function MetricsDropDownValueChanged(app, event)
            updateFrequencyDomain(app);
        end

        % Callback function
        function MovwindowsampEditFieldValueChanged(app, event)
            updateFrequencyDomain(app);
        end

        % Value changed function: VLF1
        function VLF1ValueChanged(app, event)
            value = app.VLF1.Value;
            if(value >= app.VLF2.Value)  % reset to default
                app.VLF1.Value = 0.003;
                app.VLF2.Value = 0.04;
            end
            updateFrequencyDomain(app);
        end

        % Value changed function: VLF2
        function VLF2ValueChanged(app, event)
            value = app.VLF2.Value;
            if(value <= app.VLF1.Value)  % reset to default
                app.VLF1.Value = 0.003;
                app.VLF2.Value = 0.04;
            end
            updateFrequencyDomain(app);
        end

        % Value changed function: LF1
        function LF1ValueChanged(app, event)
            value = app.LF1.Value;
            if(value >= app.LF2.Value)  % reset to default
                app.LF1.Value = 0.04;
                app.LF2.Value = 0.15;
            end
            updateFrequencyDomain(app);
        end

        % Value changed function: LF2
        function LF2ValueChanged(app, event)
            value = app.LF2.Value;
            if(value <= app.VLF1.Value)  % reset to default
                app.LF1.Value = 0.04;
                app.LF2.Value = 0.15;
            end
            updateFrequencyDomain(app);
        end

        % Value changed function: HF1
        function HF1ValueChanged(app, event)
            value = app.HF1.Value;
            if(value >= app.HF2.Value)  % reset to default
                app.HF1.Value = 0.15;
                app.HF2.Value = 0.4;
            end
            updateFrequencyDomain(app);
        end

        % Value changed function: HF2
        function HF2ValueChanged(app, event)
            value = app.HF2.Value;
            if(value <= app.HF1.Value)  % reset to default
                app.HF1.Value = 0.15;
                app.HF2.Value = 0.4;
            end
            updateFrequencyDomain(app);
        end

        % Value changed function: ARorderEditField
        function ARorderEditFieldValueChanged(app, event)
            value = app.ARorderEditField.Value;
            if(floor(value) ~= value)
                app.ARorderEditField.Value = floor(value);
            end
            if(value < 1)
                app.ARorderEditField.Value = 1;
            end
            updateFrequencyDomain(app);
        end

        % Value changed function: WelchovrEditField
        function WelchovrEditFieldValueChanged(app, event)
            value = app.WelchovrEditField.Value;
            if(value < 0 || value > 100)% reset back to default
                app.WelchovrEditField.Value = 50;
            end
            updateFrequencyDomain(app); 
        end

        % Value changed function: WindowminEditField
        function WindowminEditFieldValueChanged(app, event)
            value = app.WindowminEditField.Value;
            if(value < 5)
                app.WindowminEditField.Value = 5;
            end
            updateFrequencyDomain(app); 
        end

        % Value changed function: LombCheckBox
        function LombCheckBoxValueChanged(app, event)
            if(~app.LombCheckBox.Value && ~app.ARCheckBox.Value && ~app.WelchCheckBox.Value && ~app.FFTCheckBox.Value)
                app.LombCheckBox.Value = true;
            end
            updateFrequencyDomain(app); 
        end

        % Value changed function: ARCheckBox
        function ARCheckBoxValueChanged(app, event)
            if(~app.LombCheckBox.Value && ~app.ARCheckBox.Value && ~app.WelchCheckBox.Value && ~app.FFTCheckBox.Value)
                app.LombCheckBox.Value = true;
            end
            updateFrequencyDomain(app); 
        end

        % Value changed function: WelchCheckBox
        function WelchCheckBoxValueChanged(app, event)
            if(~app.LombCheckBox.Value && ~app.ARCheckBox.Value && ~app.WelchCheckBox.Value && ~app.FFTCheckBox.Value)
                app.LombCheckBox.Value = true;
            end
            updateFrequencyDomain(app); 
        end

        % Value changed function: FFTCheckBox
        function FFTCheckBoxValueChanged(app, event)
            if(~app.LombCheckBox.Value && ~app.ARCheckBox.Value && ~app.WelchCheckBox.Value && ~app.FFTCheckBox.Value)
                app.LombCheckBox.Value = true;
            end
            updateFrequencyDomain(app); 
        end

        % Value changed function: LogscaleCheckBox
        function LogscaleCheckBoxValueChanged(app, event)
            value = app.LogscaleCheckBox.Value;
            if(value)
                set(app.UIAxes6, 'YScale', 'log');
            else
                set(app.UIAxes6, 'YScale', 'linear');
            end
        end

        % Value changed function: TrendremovalorderEditField
        function TrendremovalorderEditFieldValueChanged(app, event)
            value = app.TrendremovalorderEditField.Value;
            if(floor(value) ~= value)
                app.TrendremovalorderEditField.Value = floor(value);
            end
            if(value ~= -1 && value < 0)
                app.TrendremovalorderEditField.Value = 0;
            end
            updateFrequencyDomain(app);
        end

        % Callback function
        function MinscaleEditFieldValueChanged(app, event)
            value = app.MinscaleEditField.Value;
            if(value < 1)
                app.MinscaleEditField.Value = 1;
            end
            if(value > app.MaxscaleEditField.Value)
                app.MinscaleEditField.Value = 1;
                app.MaxscaleEditField.Value = 15;
            end
            updateMSE(app);
        end

        % Value changed function: MaxscaleEditField
        function MaxscaleEditFieldValueChanged(app, event)
            value = app.MaxscaleEditField.Value;
            if(value < 1)
                app.MinscaleEditField.Value = 1;
            end
            if(value < app.MinscaleEditField.Value)
                app.MinscaleEditField.Value = 1;
                app.MaxscaleEditField.Value = 15;
            end
            updateMSE(app);
        end

        % Button pushed function: FitalldataButton
        function FitalldataButtonPushed(app, event)
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: FitverticallyButton
        function FitverticallyButtonPushed(app, event)
            x_lim = round(xlim(app.UIAxes) * app.Fs) + 1;
            x_lim(1) = max(1, x_lim(1));
            x_lim(end) = min(length(app.ecg), x_lim(end));
            data = app.ecg(x_lim(1) : x_lim(2));
            y_lim = [min(data), max(data)];
            y_h = y_lim(2) - y_lim(1);
            ylim(app.UIAxes, [y_lim(1)-y_h*0.1, y_lim(2)+y_h*0.1]);
        end

        % Selection changed function: ToolsButtonGroup
        function ToolsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ToolsButtonGroup.SelectedObject;
            switch selectedButton
                case app.ZoomButton
                    zoom(app.UIAxes, 'on');
                    
                case app.PanButton
                    pan(app.UIAxes, 'on');
            end
        end

        % Value changed function: PossEditField
        function PossEditFieldValueChanged(app, event)
            value = app.PossEditField.Value;
            if(value < 0)
                app.PossEditField.Value = 0;
            end
            if(value > app.duration - 1)
                app.PossEditField.Value = app.duration - 1;
            end
            if(value + app.SizesEditField.Value > app.duration)
                app.PossEditField.Value = floor(app.duration - app.SizesEditField.Value);
            end
        end

        % Value changed function: SizesEditField
        function SizesEditFieldValueChanged(app, event)
            value = app.SizesEditField.Value;
            if(value < 0)
                app.SizesEditField.Value = 5;
            end
            if(value > app.duration)
                app.SizesEditField.Value = app.duration;
            end
            if(value + app.PossEditField.Value > app.duration)
                app.SizesEditField.Value = floor(app.duration - app.PossEditField.Value);
            end
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            s = app.SizesEditField.Value;
            p = app.PossEditField.Value;
            x_lim = ([p, p+s] .* app.Fs) + 1;
            data = app.ecg(x_lim(1) : x_lim(2));
            y_lim = [min(data), max(data)];
            xlim(app.UIAxes, [p, p+s]);
            y_h = y_lim(2) - y_lim(1);
            ylim(app.UIAxes, [y_lim(1)-y_h*0.1, y_lim(2)+y_h*0.1]);
        end

        % Button pushed function: FitalldataButton_2
        function FitalldataButton_2Pushed(app, event)
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: FitverticallyButton_2
        function FitverticallyButton_2Pushed(app, event)
            x_lim = round(xlim(app.UIAxes2) * app.Fs) + 1;
            x_lim(1) = max(1, x_lim(1));
            x_lim(end) = min(length(app.ecg), x_lim(end));
            data = app.ecg(x_lim(1) : x_lim(2));
            y_lim = [min(data), max(data)];
            y_h = y_lim(2) - y_lim(1);
            ylim(app.UIAxes2, [y_lim(1)-y_h*0.1, y_lim(2)+y_h*0.1]);
        end

        % Value changed function: PossEditField_2
        function PossEditField_2ValueChanged(app, event)
            value = app.PossEditField_2.Value;
            if(value < app.intervalInSecondsForPeaks(1))
                app.PossEditField_2.Value = app.intervalInSecondsForPeaks(1);
            end
            if(value > app.intervalInSecondsForPeaks(end) - 1)
                app.PossEditField_2.Value = floor(app.intervalInSecondsForPeaks(end) - 1);
            end
            if(value + app.SizesEditField_2.Value > app.intervalInSecondsForPeaks(end))
                app.PossEditField_2.Value = floor(app.intervalInSecondsForPeaks(end) - app.SizesEditField_2.Value);
            end
        end

        % Value changed function: SizesEditField_2
        function SizesEditField_2ValueChanged(app, event)
            value = app.SizesEditField_2.Value;
            if(value < 0)
                app.SizesEditField_2.Value = min(5, app.intervalInSecondsForPeaks(end) - app.intervalInSecondsForPeaks(1));
            end
            if(value > app.intervalInSecondsForPeaks(end))
                app.SizesEditField_2.Value = floor(app.intervalInSecondsForPeaks(end));
            end
            if(value + app.PossEditField_2.Value > app.intervalInSecondsForPeaks(end))
                app.SizesEditField_2.Value = floor(app.intervalInSecondsForPeaks(end) - app.PossEditField_2.Value);
            end
        end

        % Button pushed function: FitButton_2
        function FitButton_2Pushed(app, event)
            s = app.SizesEditField_2.Value;
            p = app.PossEditField_2.Value;
            x_lim = ([p, p+s] .* app.Fs) + 1;
            data = app.ecg(x_lim(1) : x_lim(2));
            y_lim = [min(data), max(data)];
            xlim(app.UIAxes2, [p, p+s]);
            y_h = y_lim(2) - y_lim(1);
            ylim(app.UIAxes2, [y_lim(1)-y_h*0.1, y_lim(2)+y_h*0.1]);
        end

        % Selection changed function: ToolsButtonGroup_2
        function ToolsButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ToolsButtonGroup_2.SelectedObject;
            switch selectedButton
                case app.ZoomButton_2
                    zoom(app.UIAxes2, 'on');
                    
                case app.PanButton_2
                    pan(app.UIAxes2, 'on');
            end
        end

        % Button pushed function: GeneratereportButton
        function GeneratereportButtonPushed(app, event)
            d = uiprogressdlg(app.HRVMetricsUIFigure, 'Title', 'Please wait...', 'Message', 'Generating the report...', 'Indeterminate', 'on');
            
            import mlreportgen.dom.*;
            
            %folder_path = app.PathtosaveEditField.Value;
            %folder_path = [folder_path, "\test_Report"];
            
            folder_path = 'C:\Users\joser\Documents\+++ Faculdade +++\4ano_2sem\Processamento de Sinais em Bioengenharia\Project PDSB\Matlab code\test_Report\';
        
            % run all the analysis
            if(~app.peak_tab_done)
               getPeaks(app);
               runPeakDetection(app);
            end
            
            if(~app.tachogram_tab_done)
               updateTachogram(app);
            end
            
%             if(~app.geom_tab_done)
%                updateGeometry(app);
%             end
            
            if(~app.timeseries_tab_done)
               updateTimeSeries(app);
            end
            
            if(~app.freq_tab_done)
               updateFrequencyDomain(app);
            end
            
            if(~app.poincare_tab_done)
               updatePoincare(app);
            end
            
            if(~app.entropy_tab_done)
               updateAproxEntropy(app);
               updateSampleEntropy(app);
               updateMSE(app);
            end

            if(~app.DFA_tab_done)
               updateDFA(app);
            end
            
            if(~app.recurrence_tab_done)
               updateRecurr(app);
            end

            D = Document([folder_path, 'Report_results'], 'docx', 'template.dotx');
            open(D);

            % ECG plot
            saveAxisAsImage(app, app.UIAxes, folder_path,'ECG_image');
            ECG_img = Image([folder_path,'ECG_image.jpg']);
            ECG_img.Style = {ScaleToFit};
            ECG_img.Height = "12cm";
            moveToNextHole(D);
            append(D, ECG_img);
            
            % Peak detection
            saveAxisAsImage(app, app.UIAxes2, folder_path,'PeakDet_image');
            PeakDet_img = Image([folder_path,'PeakDet_image.jpg']);
            PeakDet_img.Style = {ScaleToFit};
            moveToNextHole(D);
            append(D, PeakDet_img);
            
            moveToNextHole(D);
            append(D, num2str(length(app.peak_locs)));
            
            % Tachogram
            saveAxisAsImage(app, app.UIAxes4, folder_path,'RRi_image');
            RRi_img = Image([folder_path,'RRi_image.jpg']);
            RRi_img.Style = {ScaleToFit};
            RRi_img.Height = "10cm";
            moveToNextHole(D);
            append(D, RRi_img);
            
            saveAxisAsImage(app, app.UIAxes4_2, folder_path,'BPM_image');
            BPM_img = Image([folder_path,'BPM_image.jpg']);
            BPM_img.Style = {ScaleToFit};
            BPM_img.Height = "10cm";
            moveToNextHole(D);
            append(D, BPM_img);
        
            % Time domain
            moveToNextHole(D);
            append(D, [num2str(round(app.metric_TS_SDNN,2)), ' ms']);
            moveToNextHole(D);
            append(D, [num2str(round(app.metric_TS_SDANN,2)), ' ms']);
            moveToNextHole(D);
            append(D, [num2str(round(app.metric_TS_SDNNindex,2)), ' ms']);
            moveToNextHole(D);
            append(D, [num2str(round(app.metric_TS_RMSSD,2)), ' ms']);
            moveToNextHole(D);
            append(D, [num2str(round(app.metric_TS_SDSD,2)), ' ms']);
            moveToNextHole(D);
            append(D, num2str(round(app.metric_TS_NN50,2)));
            moveToNextHole(D);
            append(D, num2str(round(app.metric_TS_NN50i,2)));
            moveToNextHole(D);
            append(D, num2str(round(app.metric_TS_NN50d,2)));
            moveToNextHole(D);
            append(D, [num2str(round(app.metric_TS_pNN50,2)), ' %']);
            
            % Frequency domain
            moveToNextHole(D);
            append(D, [num2str(round(app.vlf_power,1)), ' ms^2']);
            moveToNextHole(D);
            append(D, [num2str(round(app.lf_power,1)), ' ms^2']);
            moveToNextHole(D);
            append(D, [num2str(round(app.hf_power,1)), ' ms^2']);
            moveToNextHole(D);
            append(D, [num2str(round(app.vlf_rel,1)), '%']);
            moveToNextHole(D);
            append(D, [num2str(round(app.lf_rel,1)), '%']);
            moveToNextHole(D);
            append(D, [num2str(round(app.hf_rel,1)), '%']);
            moveToNextHole(D);
            append(D, [num2str(round(app.lf_norm,1)), '%']);
            moveToNextHole(D);
            append(D, [num2str(round(app.hf_norm,2)), '%']);
            moveToNextHole(D);
            append(D, [num2str(app.lf_to_hf), '']);
            moveToNextHole(D);
            append(D, [num2str(round(app.lf_peak_freq,3)), ' Hz']);
            moveToNextHole(D);
            append(D, [num2str(round(app.hf_peak_freq,3)), ' Hz']);
            
            saveAxisAsImage(app, app.UIAxes6, folder_path,'Spectrum_image');
            Spectrum_img = Image([folder_path,'Spectrum_image.jpg']);
            Spectrum_img.Style = {ScaleToFit};
            moveToNextHole(D);
            append(D, Spectrum_img);
            
            
            % Non-linear
            
            % Poincare plot
            saveAxisAsImage(app, app.UIAxes7, folder_path,'Poincare_image');
            Poincare_img = Image([folder_path,'Poincare_image.jpg']);
            Poincare_img.Style = {ScaleToFit};
            moveToNextHole(D);
            append(D, Poincare_img);
            
            % DFA
            saveAxisAsImage(app, app.UIAxes8, folder_path,'DFA_image');
            DFA_img = Image([folder_path,'DFA_image.jpg']);
            DFA_img.Style = {ScaleToFit};
            moveToNextHole(D);
            append(D, DFA_img);
            
            % MSE plot
            saveAxisAsImage(app, app.UIAxes10, folder_path,'MSE_image');
            MSE_img = Image([folder_path,'MSE_image.jpg']);
            MSE_img.Style = {ScaleToFit};
            moveToNextHole(D);
            append(D, MSE_img);
            
            % Recurrence plot
            saveAxisAsImage(app, app.UIAxes9, folder_path,'Recurrence_image');
            Recurrence_img = Image([folder_path,'Recurrence_image.jpg']);
            Recurrence_img.Style = {ScaleToFit};
            moveToNextHole(D);
            append(D, Recurrence_img);
            close(D);
            
            rptview([folder_path, 'Report_results.docx'], 'pdf'); 
            
            close(d);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path = uigetdir();
            app.PathtosaveEditField.Value = path;
            app.HRVMetricsUIFigure.Visible = 'off';
            app.HRVMetricsUIFigure.Visible = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HRVMetricsUIFigure = uifigure;
            app.HRVMetricsUIFigure.Name = 'HRV Metrics';
            app.TabGroup = uitabgroup(app.HRVMetricsUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LoadTab = uitab(app.TabGroup);
            app.LoadTab.Title = 'Load';
            app.UIAxes = uiaxes(app.LoadTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.FileInfo = uipanel(app.LoadTab);
            app.FileInfo.Title = 'File info';
            app.SelectedchannelDropDownLabel = uilabel(app.FileInfo);
            app.SelectedchannelDropDownLabel.Text = 'Selected channel';
            app.SelectedchannelDropDown = uidropdown(app.FileInfo);
            app.SelectedchannelDropDown.Items = {'1'};
            app.SelectedchannelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedchannelDropDownValueChanged, true);
            app.SelectedchannelDropDown.Value = '1';
            app.FilenameEditFieldLabel = uilabel(app.FileInfo);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.FileInfo, 'text');
            app.FilenameEditField.Editable = 'off';
            app.SamplingfrequencyEditFieldLabel = uilabel(app.FileInfo);
            app.SamplingfrequencyEditFieldLabel.Text = 'Sampling frequency';
            app.SamplingfrequencyEditField = uieditfield(app.FileInfo, 'text');
            app.SamplingfrequencyEditField.Editable = 'off';
            app.NumberofchannelsEditFieldLabel = uilabel(app.FileInfo);
            app.NumberofchannelsEditFieldLabel.Text = 'Number of channels';
            app.NumberofchannelsEditField = uieditfield(app.FileInfo, 'text');
            app.NumberofchannelsEditField.Editable = 'off';
            app.DurationEditFieldLabel = uilabel(app.FileInfo);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.FileInfo, 'text');
            app.DurationEditField.Editable = 'off';
            app.NumberofsamplesEditFieldLabel = uilabel(app.FileInfo);
            app.NumberofsamplesEditFieldLabel.Text = 'Number of samples';
            app.NumberofsamplesEditField = uieditfield(app.FileInfo, 'text');
            app.NumberofsamplesEditField.Editable = 'off';
            app.TimeselectionPanel = uipanel(app.LoadTab);
            app.TimeselectionPanel.Title = 'Time selection';
            app.UpdateselectionButton = uibutton(app.TimeselectionPanel, 'push');
            app.UpdateselectionButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateselectionButtonPushed, true);
            app.UpdateselectionButton.Enable = 'off';
            app.UpdateselectionButton.Text = 'Update selection';
            app.DurationLabel_2 = uilabel(app.TimeselectionPanel);
            app.DurationLabel_2.Enable = 'off';
            app.DurationLabel_2.Text = 'Duration:';
            app.useSameInterval = uicheckbox(app.TimeselectionPanel);
            app.useSameInterval.ValueChangedFcn = createCallbackFcn(app, @useSameIntervalValueChanged, true);
            app.useSameInterval.Text = 'Use this interval for analysis';
            app.SamplesLabel = uilabel(app.TimeselectionPanel);
            app.SamplesLabel.Enable = 'off';
            app.SamplesLabel.Text = 'Samples:';
            app.ControlpanelPanel = uipanel(app.LoadTab);
            app.ControlpanelPanel.Title = 'Control panel';
            app.LoadfileButton = uibutton(app.ControlpanelPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Icon = 'load.png';
            app.LoadfileButton.Text = 'Load file';
            app.CleardataButton = uibutton(app.ControlpanelPanel, 'push');
            app.CleardataButton.ButtonPushedFcn = createCallbackFcn(app, @CleardataButtonPushed, true);
            app.CleardataButton.Icon = 'clear.png';
            app.CleardataButton.Enable = 'off';
            app.CleardataButton.Text = 'Clear data';
            app.PlotcontrolPanel_2 = uipanel(app.LoadTab);
            app.PlotcontrolPanel_2.Title = 'Plot control';
            app.PlotcontrolPanel_2.Visible = 'off';
            app.FitalldataButton = uibutton(app.PlotcontrolPanel_2, 'push');
            app.FitalldataButton.ButtonPushedFcn = createCallbackFcn(app, @FitalldataButtonPushed, true);
            app.FitalldataButton.Icon = 'fit.png';
            app.FitalldataButton.Text = 'Fit all data';
            app.FitverticallyButton = uibutton(app.PlotcontrolPanel_2, 'push');
            app.FitverticallyButton.ButtonPushedFcn = createCallbackFcn(app, @FitverticallyButtonPushed, true);
            app.FitverticallyButton.Icon = 'fit_vertical.png';
            app.FitverticallyButton.Text = 'Fit vertically';
            app.FitButton = uibutton(app.PlotcontrolPanel_2, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Tooltip = {'Plot the data with the specified position and window size (values above button)'};
            app.FitButton.Text = 'Fit';
            app.SizesEditFieldLabel = uilabel(app.PlotcontrolPanel_2);
            app.SizesEditFieldLabel.Text = 'Size (s)';
            app.SizesEditField = uieditfield(app.PlotcontrolPanel_2, 'numeric');
            app.SizesEditField.ValueChangedFcn = createCallbackFcn(app, @SizesEditFieldValueChanged, true);
            app.SizesEditField.Tooltip = {'Size of the window to fit (seconds)'};
            app.SizesEditField.Value = 10;
            app.PossEditFieldLabel = uilabel(app.PlotcontrolPanel_2);
            app.PossEditFieldLabel.Text = 'Pos. (s)';
            app.PossEditField = uieditfield(app.PlotcontrolPanel_2, 'numeric');
            app.PossEditField.ValueChangedFcn = createCallbackFcn(app, @PossEditFieldValueChanged, true);
            app.PossEditField.Tooltip = {'Position of the window to fit (seconds)'};
            app.ToolsButtonGroup = uibuttongroup(app.PlotcontrolPanel_2);
            app.ToolsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ToolsButtonGroupSelectionChanged, true);
            app.ToolsButtonGroup.Title = 'Tools';
            app.ZoomButton = uitogglebutton(app.ToolsButtonGroup);
            app.ZoomButton.Text = 'Zoom';
            app.ZoomButton.Value = true;
            app.PanButton = uitogglebutton(app.ToolsButtonGroup);
            app.PanButton.Text = 'Pan';
            app.PeakdetTab = uitab(app.TabGroup);
            app.PeakdetTab.Title = 'Peak det.';
            app.UIAxes2 = uiaxes(app.PeakdetTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.PeaktopeakanalysisPanel = uipanel(app.PeakdetTab);
            app.PeaktopeakanalysisPanel.Title = 'Peak-to-peak analysis';
            app.NumberofpeaksEditFieldLabel = uilabel(app.PeaktopeakanalysisPanel);
            app.NumberofpeaksEditFieldLabel.Text = 'Number of peaks';
            app.NumberofpeaksEditField = uieditfield(app.PeaktopeakanalysisPanel, 'text');
            app.NumberofpeaksEditField.Editable = 'off';
            app.AverageBPMEditFieldLabel = uilabel(app.PeaktopeakanalysisPanel);
            app.AverageBPMEditFieldLabel.Text = 'Average BPM';
            app.AverageBPMEditField = uieditfield(app.PeaktopeakanalysisPanel, 'text');
            app.AverageBPMEditField.Editable = 'off';
            app.BPMStddeviationEditFieldLabel = uilabel(app.PeaktopeakanalysisPanel);
            app.BPMStddeviationEditFieldLabel.Text = 'BPM Std. deviation';
            app.BPMStddeviationEditField = uieditfield(app.PeaktopeakanalysisPanel, 'text');
            app.BPMStddeviationEditField.Editable = 'off';
            app.PlotcontrolPanel = uipanel(app.PeakdetTab);
            app.PlotcontrolPanel.Title = 'Plot control';
            app.FitalldataButton_2 = uibutton(app.PlotcontrolPanel, 'push');
            app.FitalldataButton_2.ButtonPushedFcn = createCallbackFcn(app, @FitalldataButton_2Pushed, true);
            app.FitalldataButton_2.Icon = 'fit.png';
            app.FitalldataButton_2.Text = 'Fit all data';
            app.FitverticallyButton_2 = uibutton(app.PlotcontrolPanel, 'push');
            app.FitverticallyButton_2.ButtonPushedFcn = createCallbackFcn(app, @FitverticallyButton_2Pushed, true);
            app.FitverticallyButton_2.Icon = 'fit_vertical.png';
            app.FitverticallyButton_2.Text = 'Fit vertically';
            app.ToolsButtonGroup_2 = uibuttongroup(app.PlotcontrolPanel);
            app.ToolsButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ToolsButtonGroup_2SelectionChanged, true);
            app.ToolsButtonGroup_2.Title = 'Tools';
            app.ZoomButton_2 = uitogglebutton(app.ToolsButtonGroup_2);
            app.ZoomButton_2.Text = 'Zoom';
            app.ZoomButton_2.Value = true;
            app.PanButton_2 = uitogglebutton(app.ToolsButtonGroup_2);
            app.PanButton_2.Text = 'Pan';
            app.FitButton_2 = uibutton(app.PlotcontrolPanel, 'push');
            app.FitButton_2.ButtonPushedFcn = createCallbackFcn(app, @FitButton_2Pushed, true);
            app.FitButton_2.Tooltip = {'Plot the data with the specified position and window size (values above button)'};
            app.FitButton_2.Text = 'Fit';
            app.SizesEditField_2Label = uilabel(app.PlotcontrolPanel);
            app.SizesEditField_2Label.Text = 'Size (s)';
            app.SizesEditField_2 = uieditfield(app.PlotcontrolPanel, 'numeric');
            app.SizesEditField_2.ValueChangedFcn = createCallbackFcn(app, @SizesEditField_2ValueChanged, true);
            app.SizesEditField_2.Tooltip = {'Size of the window to fit (seconds)'};
            app.SizesEditField_2.Value = 10;
            app.PossEditField_2Label = uilabel(app.PlotcontrolPanel);
            app.PossEditField_2Label.Text = 'Pos. (s)';
            app.PossEditField_2 = uieditfield(app.PlotcontrolPanel, 'numeric');
            app.PossEditField_2.ValueChangedFcn = createCallbackFcn(app, @PossEditField_2ValueChanged, true);
            app.PossEditField_2.Tooltip = {'Position of the window to fit (seconds)'};
            app.TachogramTab = uitab(app.TabGroup);
            app.TachogramTab.Title = 'Tachogram';
            app.UIAxes4 = uiaxes(app.TachogramTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes4_2 = uiaxes(app.TachogramTab);
            title(app.UIAxes4_2, 'Title')
            xlabel(app.UIAxes4_2, 'X')
            ylabel(app.UIAxes4_2, 'Y')
            app.OutlierscontrolPanel = uipanel(app.TachogramTab);
            app.OutlierscontrolPanel.Title = 'Outliers control';
            app.RemoveoutliersCheckBox = uicheckbox(app.OutlierscontrolPanel);
            app.RemoveoutliersCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveoutliersCheckBoxValueChanged, true);
            app.RemoveoutliersCheckBox.Text = 'Remove outliers';
            app.RemoveoutliersCheckBox.Value = true;
            app.MethodButtonGroup = uibuttongroup(app.OutlierscontrolPanel);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.MethodButtonGroup.Title = 'Method';
            app.MeanButton = uiradiobutton(app.MethodButtonGroup);
            app.MeanButton.Enable = 'off';
            app.MeanButton.Tooltip = {'Outliers are defined as elements more than three standard deviations from the mean. This method is faster but less robust than ''Median''.'};
            app.MeanButton.Text = 'Mean';
            app.MedianButton = uiradiobutton(app.MethodButtonGroup);
            app.MedianButton.Enable = 'off';
            app.MedianButton.Tooltip = {'Outliers are defined as elements more than three scaled MAD from the median. The scaled MAD is defined as c*median(abs(A-median(A)))'; ' where c=-1/(sqrt(2)*erfcinv(3/2)).'};
            app.MedianButton.Text = 'Median';
            app.MovingmeanButton = uiradiobutton(app.MethodButtonGroup);
            app.MovingmeanButton.Enable = 'off';
            app.MovingmeanButton.Tooltip = {'Outliers are defined as elements more than three local standard deviations from the local mean over a window length specified by window.'};
            app.MovingmeanButton.Text = 'Moving mean';
            app.MovingmedianButton = uiradiobutton(app.MethodButtonGroup);
            app.MovingmedianButton.Enable = 'off';
            app.MovingmedianButton.Tooltip = {'Outliers are defined as elements more than three local scaled MAD from the local median over a window length specified by window.'; 'The scaled MAD is defined as c*median(abs(A-median(A)))'; ' where c=-1/(sqrt(2)*erfcinv(3/2)).'};
            app.MovingmedianButton.Text = 'Moving median';
            app.MovingmedianButton.Value = true;
            app.ComparetoprevButton = uiradiobutton(app.MethodButtonGroup);
            app.ComparetoprevButton.Enable = 'off';
            app.ComparetoprevButton.Tooltip = {'Identifies an outlier if the interval is larger than the previous interval by a defined percentage theshold.'};
            app.ComparetoprevButton.Text = 'Compare to prev.';
            app.WindowsizesEditFieldLabel = uilabel(app.OutlierscontrolPanel);
            app.WindowsizesEditFieldLabel.Text = 'Window size (s)';
            app.WindowsizesEditField = uieditfield(app.OutlierscontrolPanel, 'numeric');
            app.WindowsizesEditField.ValueChangedFcn = createCallbackFcn(app, @WindowsizesEditFieldValueChanged, true);
            app.WindowsizesEditField.Enable = 'off';
            app.WindowsizesEditField.Tooltip = {'Window size for outlier methods with moving windows (in seconds)'};
            app.WindowsizesEditField.Value = 60;
            app.NumberofoutliersEditFieldLabel = uilabel(app.OutlierscontrolPanel);
            app.NumberofoutliersEditFieldLabel.Text = 'Number of outliers';
            app.NumberofoutliersEditField = uieditfield(app.OutlierscontrolPanel, 'text');
            app.NumberofoutliersEditField.Editable = 'off';
            app.ComparetoprevthresholdEditFieldLabel = uilabel(app.OutlierscontrolPanel);
            app.ComparetoprevthresholdEditFieldLabel.Text = {'Compare to prev.'; 'threshold (%)'; ''};
            app.ComparetoprevthresholdEditField = uieditfield(app.OutlierscontrolPanel, 'numeric');
            app.ComparetoprevthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ComparetoprevthresholdEditFieldValueChanged, true);
            app.ComparetoprevthresholdEditField.Enable = 'off';
            app.ComparetoprevthresholdEditField.Tooltip = {'Window size for outlier methods with moving windows (in seconds)'};
            app.ComparetoprevthresholdEditField.Value = 20;
            app.TimedomainTab = uitab(app.TabGroup);
            app.TimedomainTab.Title = 'Time domain';
            app.StatisticalmetricsPanel = uipanel(app.TimedomainTab);
            app.StatisticalmetricsPanel.Title = 'Statistical metrics';
            app.SDNNEditFieldLabel = uilabel(app.StatisticalmetricsPanel);
            app.SDNNEditFieldLabel.Text = 'SDNN';
            app.SDNNEditField = uieditfield(app.StatisticalmetricsPanel, 'text');
            app.SDNNEditField.Editable = 'off';
            app.SDNNEditField.Tooltip = {'Standard deviation of all NN intervals (also known as SDRR) '};
            app.SDANNEditFieldLabel = uilabel(app.StatisticalmetricsPanel);
            app.SDANNEditFieldLabel.Text = 'SDANN';
            app.SDANNEditField = uieditfield(app.StatisticalmetricsPanel, 'text');
            app.SDANNEditField.Editable = 'off';
            app.SDANNEditField.Tooltip = {'Standard deviation of the averages of NN intervals in all 5-minutes segments'};
            app.RMSSDEditFieldLabel = uilabel(app.StatisticalmetricsPanel);
            app.RMSSDEditFieldLabel.Text = 'RMSSD';
            app.RMSSDEditField = uieditfield(app.StatisticalmetricsPanel, 'text');
            app.RMSSDEditField.Editable = 'off';
            app.RMSSDEditField.Tooltip = {'The root mean square of the intervals between adjacent NN intervals'};
            app.SDNNindexEditFieldLabel = uilabel(app.StatisticalmetricsPanel);
            app.SDNNindexEditFieldLabel.Text = 'SDNN index';
            app.SDNNindexEditField = uieditfield(app.StatisticalmetricsPanel, 'text');
            app.SDNNindexEditField.Editable = 'off';
            app.SDNNindexEditField.Tooltip = {'Mean of the standard deviations of all NN intervals in all 5-minutes segments'};
            app.SDSDEditFieldLabel = uilabel(app.StatisticalmetricsPanel);
            app.SDSDEditFieldLabel.Text = 'SDSD';
            app.SDSDEditField = uieditfield(app.StatisticalmetricsPanel, 'text');
            app.SDSDEditField.Editable = 'off';
            app.SDSDEditField.Tooltip = {'Standard deviation of differences between adjacent NN intervals'};
            app.NN50EditFieldLabel = uilabel(app.StatisticalmetricsPanel);
            app.NN50EditFieldLabel.Text = 'NN50';
            app.NN50EditField = uieditfield(app.StatisticalmetricsPanel, 'text');
            app.NN50EditField.Editable = 'off';
            app.NN50EditField.Tooltip = {'Number of pairs of adjacent NN intervals differing by more than 50 ms in the'; 'entire recording'};
            app.pNN50EditFieldLabel = uilabel(app.StatisticalmetricsPanel);
            app.pNN50EditFieldLabel.Text = 'pNN50';
            app.pNN50EditField = uieditfield(app.StatisticalmetricsPanel, 'text');
            app.pNN50EditField.Editable = 'off';
            app.pNN50EditField.Tooltip = {'Percentage of adjacent NN intervals differing by more than 50ms'};
            app.NN50Label = uilabel(app.StatisticalmetricsPanel);
            app.NN50Label.Text = 'NN50+';
            app.NN50iEditField = uieditfield(app.StatisticalmetricsPanel, 'text');
            app.NN50iEditField.Editable = 'off';
            app.NN50iEditField.Tooltip = {'Same as NN50 but with increase from the first to the second intervals'};
            app.NN50Label_2 = uilabel(app.StatisticalmetricsPanel);
            app.NN50Label_2.Text = 'NN50-';
            app.NN50dEditField = uieditfield(app.StatisticalmetricsPanel, 'text');
            app.NN50dEditField.Editable = 'off';
            app.NN50dEditField.Tooltip = {'Same as NN50 but with decrease from the first to the second intervals'};
            app.GeometricmetricsPanel = uipanel(app.TimedomainTab);
            app.GeometricmetricsPanel.Title = 'Geometric metrics';
            app.HRVtriangularindexEditFieldLabel = uilabel(app.GeometricmetricsPanel);
            app.HRVtriangularindexEditFieldLabel.Text = 'HRV triangular index';
            app.HRVtriangularindexEditField = uieditfield(app.GeometricmetricsPanel, 'text');
            app.HRVtriangularindexEditField.Editable = 'off';
            app.HRVtriangularindexEditField.Tooltip = {'Total number of all RR intervals divided by the height of the histogram of all RR intervals.'};
            app.TINNEditFieldLabel = uilabel(app.GeometricmetricsPanel);
            app.TINNEditFieldLabel.Text = 'TINN';
            app.TINNEditField = uieditfield(app.GeometricmetricsPanel, 'text');
            app.TINNEditField.Editable = 'off';
            app.ShowtriangularinterpolationCheckBox = uicheckbox(app.GeometricmetricsPanel);
            app.ShowtriangularinterpolationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowtriangularinterpolationCheckBoxValueChanged, true);
            app.ShowtriangularinterpolationCheckBox.Text = 'Show triangular interpolation';
            app.ShowtriangularinterpolationCheckBox.Value = true;
            app.LogaritmicindexEditFieldLabel = uilabel(app.GeometricmetricsPanel);
            app.LogaritmicindexEditFieldLabel.Text = 'Logaritmic index';
            app.LogaritmicindexEditField = uieditfield(app.GeometricmetricsPanel, 'text');
            app.LogaritmicindexEditField.Editable = 'off';
            app.ShowexponencialinterpolationCheckBox = uicheckbox(app.GeometricmetricsPanel);
            app.ShowexponencialinterpolationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowexponencialinterpolationCheckBoxValueChanged, true);
            app.ShowexponencialinterpolationCheckBox.Text = 'Show exponencial interpolation';
            app.ShowexponencialinterpolationCheckBox.Value = true;
            app.GeometryAxis = uiaxes(app.TimedomainTab);
            title(app.GeometryAxis, 'Title')
            xlabel(app.GeometryAxis, 'X')
            ylabel(app.GeometryAxis, 'Y')
            app.GeometryAxis2 = uiaxes(app.TimedomainTab);
            title(app.GeometryAxis2, 'Title')
            xlabel(app.GeometryAxis2, 'X')
            ylabel(app.GeometryAxis2, 'Y')
            app.FrequencydomainTab = uitab(app.TabGroup);
            app.FrequencydomainTab.Title = 'Frequency domain';
            app.UIAxes6 = uiaxes(app.FrequencydomainTab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.SpectrummetricsPanel = uipanel(app.FrequencydomainTab);
            app.SpectrummetricsPanel.Title = 'Spectrum metrics';
            app.VLFPowerEditFieldLabel = uilabel(app.SpectrummetricsPanel);
            app.VLFPowerEditFieldLabel.Text = 'VLF Power';
            app.VLFPowerEditField = uieditfield(app.SpectrummetricsPanel, 'text');
            app.VLFPowerEditField.Editable = 'off';
            app.LFPowerEditFieldLabel = uilabel(app.SpectrummetricsPanel);
            app.LFPowerEditFieldLabel.Text = 'LF Power';
            app.LFPowerEditField = uieditfield(app.SpectrummetricsPanel, 'text');
            app.LFPowerEditField.Editable = 'off';
            app.HFPowerEditFieldLabel = uilabel(app.SpectrummetricsPanel);
            app.HFPowerEditFieldLabel.Text = 'HF Power';
            app.HFPowerEditField = uieditfield(app.SpectrummetricsPanel, 'text');
            app.HFPowerEditField.Editable = 'off';
            app.VLFEditFieldLabel = uilabel(app.SpectrummetricsPanel);
            app.VLFEditFieldLabel.Text = 'VLF';
            app.VLFEditField = uieditfield(app.SpectrummetricsPanel, 'text');
            app.VLFEditField.Editable = 'off';
            app.LFEditFieldLabel = uilabel(app.SpectrummetricsPanel);
            app.LFEditFieldLabel.Text = 'LF';
            app.LFEditField = uieditfield(app.SpectrummetricsPanel, 'text');
            app.LFEditField.Editable = 'off';
            app.HFEditFieldLabel = uilabel(app.SpectrummetricsPanel);
            app.HFEditFieldLabel.Text = 'HF';
            app.HFEditField = uieditfield(app.SpectrummetricsPanel, 'text');
            app.HFEditField.Editable = 'off';
            app.LFnormEditFieldLabel = uilabel(app.SpectrummetricsPanel);
            app.LFnormEditFieldLabel.Text = 'LF norm';
            app.LFnormEditField = uieditfield(app.SpectrummetricsPanel, 'text');
            app.LFnormEditField.Editable = 'off';
            app.HFnormEditFieldLabel = uilabel(app.SpectrummetricsPanel);
            app.HFnormEditFieldLabel.Text = 'HF norm';
            app.HFnormEditField = uieditfield(app.SpectrummetricsPanel, 'text');
            app.HFnormEditField.Editable = 'off';
            app.LFHFEditFieldLabel = uilabel(app.SpectrummetricsPanel);
            app.LFHFEditFieldLabel.Text = 'LF/HF';
            app.LFHFEditField = uieditfield(app.SpectrummetricsPanel, 'text');
            app.LFHFEditField.Editable = 'off';
            app.PeakLFEditFieldLabel = uilabel(app.SpectrummetricsPanel);
            app.PeakLFEditFieldLabel.Text = 'Peak LF';
            app.PeakLFEditField = uieditfield(app.SpectrummetricsPanel, 'text');
            app.PeakLFEditField.Editable = 'off';
            app.PeakHFEditFieldLabel = uilabel(app.SpectrummetricsPanel);
            app.PeakHFEditFieldLabel.Text = 'Peak HF';
            app.PeakHFEditField = uieditfield(app.SpectrummetricsPanel, 'text');
            app.PeakHFEditField.Editable = 'off';
            app.SpectrumcontrolButtonGroup = uibuttongroup(app.FrequencydomainTab);
            app.SpectrumcontrolButtonGroup.Title = 'Spectrum control';
            app.MetricsDropDownLabel = uilabel(app.SpectrumcontrolButtonGroup);
            app.MetricsDropDownLabel.Text = 'Metrics';
            app.MetricsDropDown = uidropdown(app.SpectrumcontrolButtonGroup);
            app.MetricsDropDown.Items = {'Lomb', 'AR', 'Welch', 'FFT'};
            app.MetricsDropDown.ValueChangedFcn = createCallbackFcn(app, @MetricsDropDownValueChanged, true);
            app.MetricsDropDown.Value = 'Lomb';
            app.ResampleinterpDropDownLabel = uilabel(app.SpectrumcontrolButtonGroup);
            app.ResampleinterpDropDownLabel.Text = 'Resample interp.';
            app.ResampleinterpDropDown = uidropdown(app.SpectrumcontrolButtonGroup);
            app.ResampleinterpDropDown.Items = {'Linear', 'Cubic'};
            app.ResampleinterpDropDown.ValueChangedFcn = createCallbackFcn(app, @ResampleinterpDropDownValueChanged, true);
            app.ResampleinterpDropDown.Tooltip = {'Interpolation method during resampling. Resampling is done to provide evenly sampled data to AR'; ' Wlech and FFT spectrums.'};
            app.ResampleinterpDropDown.Value = 'Cubic';
            app.ARorderEditFieldLabel = uilabel(app.SpectrumcontrolButtonGroup);
            app.ARorderEditFieldLabel.Text = 'AR order';
            app.ARorderEditField = uieditfield(app.SpectrumcontrolButtonGroup, 'numeric');
            app.ARorderEditField.ValueChangedFcn = createCallbackFcn(app, @ARorderEditFieldValueChanged, true);
            app.ARorderEditField.Tooltip = {'Auto regressive order'};
            app.ARorderEditField.Value = 16;
            app.WelchovrEditFieldLabel = uilabel(app.SpectrumcontrolButtonGroup);
            app.WelchovrEditFieldLabel.Text = 'Welch ovr.';
            app.WelchovrEditField = uieditfield(app.SpectrumcontrolButtonGroup, 'numeric');
            app.WelchovrEditField.ValueChangedFcn = createCallbackFcn(app, @WelchovrEditFieldValueChanged, true);
            app.WelchovrEditField.Tooltip = {'Welch overlap (%)'};
            app.WelchovrEditField.Value = 50;
            app.WindowminEditFieldLabel = uilabel(app.SpectrumcontrolButtonGroup);
            app.WindowminEditFieldLabel.Text = 'Window min.';
            app.WindowminEditField = uieditfield(app.SpectrumcontrolButtonGroup, 'numeric');
            app.WindowminEditField.ValueChangedFcn = createCallbackFcn(app, @WindowminEditFieldValueChanged, true);
            app.WindowminEditField.Tooltip = {'Size of the averaging window in minutes (minimum: 5 min.)'};
            app.WindowminEditField.Value = 5;
            app.LogscaleCheckBox = uicheckbox(app.SpectrumcontrolButtonGroup);
            app.LogscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogscaleCheckBoxValueChanged, true);
            app.LogscaleCheckBox.Text = 'Log scale';
            app.LogscaleCheckBox.Value = true;
            app.BandfrequenciesPanel = uipanel(app.SpectrumcontrolButtonGroup);
            app.BandfrequenciesPanel.Title = 'Band frequencies';
            app.VLFHzLabel = uilabel(app.BandfrequenciesPanel);
            app.VLFHzLabel.Text = 'VLF (Hz):';
            app.VLF1 = uieditfield(app.BandfrequenciesPanel, 'numeric');
            app.VLF1.ValueChangedFcn = createCallbackFcn(app, @VLF1ValueChanged, true);
            app.VLF1.Value = 0.003;
            app.EditFieldLabel = uilabel(app.BandfrequenciesPanel);
            app.EditFieldLabel.Text = '-';
            app.VLF2 = uieditfield(app.BandfrequenciesPanel, 'numeric');
            app.VLF2.ValueChangedFcn = createCallbackFcn(app, @VLF2ValueChanged, true);
            app.VLF2.Value = 0.04;
            app.EditField_2Label = uilabel(app.BandfrequenciesPanel);
            app.EditField_2Label.Text = '-';
            app.LF2 = uieditfield(app.BandfrequenciesPanel, 'numeric');
            app.LF2.ValueChangedFcn = createCallbackFcn(app, @LF2ValueChanged, true);
            app.LF2.Value = 0.15;
            app.HFHzLabel = uilabel(app.BandfrequenciesPanel);
            app.HFHzLabel.Text = 'HF (Hz):';
            app.HF1 = uieditfield(app.BandfrequenciesPanel, 'numeric');
            app.HF1.ValueChangedFcn = createCallbackFcn(app, @HF1ValueChanged, true);
            app.HF1.Value = 0.15;
            app.EditField_3Label = uilabel(app.BandfrequenciesPanel);
            app.EditField_3Label.Text = '-';
            app.HF2 = uieditfield(app.BandfrequenciesPanel, 'numeric');
            app.HF2.ValueChangedFcn = createCallbackFcn(app, @HF2ValueChanged, true);
            app.HF2.Value = 0.4;
            app.LFHzLabel = uilabel(app.BandfrequenciesPanel);
            app.LFHzLabel.Text = 'LF (Hz):';
            app.LF1 = uieditfield(app.BandfrequenciesPanel, 'numeric');
            app.LF1.ValueChangedFcn = createCallbackFcn(app, @LF1ValueChanged, true);
            app.LF1.Value = 0.04;
            app.MethodstoplotPanel = uipanel(app.SpectrumcontrolButtonGroup);
            app.MethodstoplotPanel.Title = 'Method(s) to plot';
            app.LombCheckBox = uicheckbox(app.MethodstoplotPanel);
            app.LombCheckBox.ValueChangedFcn = createCallbackFcn(app, @LombCheckBoxValueChanged, true);
            app.LombCheckBox.Text = 'Lomb';
            app.LombCheckBox.Value = true;
            app.ARCheckBox = uicheckbox(app.MethodstoplotPanel);
            app.ARCheckBox.ValueChangedFcn = createCallbackFcn(app, @ARCheckBoxValueChanged, true);
            app.ARCheckBox.Text = 'AR';
            app.WelchCheckBox = uicheckbox(app.MethodstoplotPanel);
            app.WelchCheckBox.ValueChangedFcn = createCallbackFcn(app, @WelchCheckBoxValueChanged, true);
            app.WelchCheckBox.Text = 'Welch';
            app.FFTCheckBox = uicheckbox(app.MethodstoplotPanel);
            app.FFTCheckBox.ValueChangedFcn = createCallbackFcn(app, @FFTCheckBoxValueChanged, true);
            app.FFTCheckBox.Text = 'FFT';
            app.TrendremovalorderEditFieldLabel = uilabel(app.SpectrumcontrolButtonGroup);
            app.TrendremovalorderEditFieldLabel.Text = 'Trend removal order';
            app.TrendremovalorderEditField = uieditfield(app.SpectrumcontrolButtonGroup, 'numeric');
            app.TrendremovalorderEditField.ValueChangedFcn = createCallbackFcn(app, @TrendremovalorderEditFieldValueChanged, true);
            app.TrendremovalorderEditField.Tooltip = {'Polynomial order of the trend removal.'; 'Order = -1 does not apply detrending.'; 'Order = 0 applies the zero-mean detrend.'; 'Order = 1 removes the linear trend.'; 'Higher order detrending is also possible.'};
            app.NonlinearanalysisTab = uitab(app.TabGroup);
            app.NonlinearanalysisTab.Title = 'Non-linear analysis';
            app.TabGroup3 = uitabgroup(app.NonlinearanalysisTab);
            app.TabGroup3.SelectionChangedFcn = createCallbackFcn(app, @TabGroup3SelectionChanged, true);
            app.PoincarplotTab = uitab(app.TabGroup3);
            app.PoincarplotTab.Title = 'Poincaré plot';
            app.UIAxes7 = uiaxes(app.PoincarplotTab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.PoincarmetricsPanel = uipanel(app.PoincarplotTab);
            app.PoincarmetricsPanel.Title = 'Poincaré metrics';
            app.SD1EditFieldLabel = uilabel(app.PoincarmetricsPanel);
            app.SD1EditFieldLabel.Text = 'SD1';
            app.SD1EditField = uieditfield(app.PoincarmetricsPanel, 'text');
            app.SD1EditField.Editable = 'off';
            app.SD1EditField.Tooltip = {'Standard deviation of the data cloud along direction 1.'};
            app.SD2EditFieldLabel = uilabel(app.PoincarmetricsPanel);
            app.SD2EditFieldLabel.Text = 'SD2';
            app.SD2EditField = uieditfield(app.PoincarmetricsPanel, 'text');
            app.SD2EditField.Editable = 'off';
            app.SD2EditField.Tooltip = {'Standard deviation of the data cloud along direction 2.'};
            app.SD3EditFieldLabel = uilabel(app.PoincarmetricsPanel);
            app.SD3EditFieldLabel.Text = 'SD3';
            app.SD3EditField = uieditfield(app.PoincarmetricsPanel, 'text');
            app.SD3EditField.Editable = 'off';
            app.SD3EditField.Tooltip = {'Standard deviation of the data cloud along direction 3.'};
            app.ControlparametersPanel_2 = uipanel(app.PoincarplotTab);
            app.ControlparametersPanel_2.Title = 'Control parameters';
            app.ShowellipseaxisCheckBox = uicheckbox(app.ControlparametersPanel_2);
            app.ShowellipseaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowellipseaxisCheckBoxValueChanged, true);
            app.ShowellipseaxisCheckBox.Text = 'Show ellipse axis';
            app.ShowellipseaxisCheckBox.Value = true;
            app.LagEditFieldLabel = uilabel(app.ControlparametersPanel_2);
            app.LagEditFieldLabel.Text = 'Lag';
            app.LagEditField = uieditfield(app.ControlparametersPanel_2, 'numeric');
            app.LagEditField.ValueChangedFcn = createCallbackFcn(app, @LagEditFieldValueChanged, true);
            app.LagEditField.Tooltip = {'Distance (in samples) between compared RR intervals'};
            app.LagEditField.Value = 1;
            app.PlotorderDropDownLabel = uilabel(app.ControlparametersPanel_2);
            app.PlotorderDropDownLabel.Text = 'Plot order';
            app.PlotorderDropDown = uidropdown(app.ControlparametersPanel_2);
            app.PlotorderDropDown.Items = {'1', '2'};
            app.PlotorderDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotorderDropDownValueChanged, true);
            app.PlotorderDropDown.Value = '1';
            app.ViewXYButton = uibutton(app.ControlparametersPanel_2, 'push');
            app.ViewXYButton.ButtonPushedFcn = createCallbackFcn(app, @ViewXYButtonPushed, true);
            app.ViewXYButton.Enable = 'off';
            app.ViewXYButton.Text = 'View XY';
            app.ViewXZButton = uibutton(app.ControlparametersPanel_2, 'push');
            app.ViewXZButton.ButtonPushedFcn = createCallbackFcn(app, @ViewXZButtonPushed, true);
            app.ViewXZButton.Enable = 'off';
            app.ViewXZButton.Text = 'View XZ';
            app.ViewZYButton = uibutton(app.ControlparametersPanel_2, 'push');
            app.ViewZYButton.ButtonPushedFcn = createCallbackFcn(app, @ViewZYButtonPushed, true);
            app.ViewZYButton.Enable = 'off';
            app.ViewZYButton.Text = 'View ZY';
            app.IsoviewButton = uibutton(app.ControlparametersPanel_2, 'push');
            app.IsoviewButton.ButtonPushedFcn = createCallbackFcn(app, @IsoviewButtonPushed, true);
            app.IsoviewButton.Enable = 'off';
            app.IsoviewButton.Text = 'Iso view';
            app.DFATab = uitab(app.TabGroup3);
            app.DFATab.Title = 'DFA';
            app.UIAxes8 = uiaxes(app.DFATab);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            app.DFAmetricsPanel = uipanel(app.DFATab);
            app.DFAmetricsPanel.Title = 'DFA metrics';
            app.Alpha1EditFieldLabel = uilabel(app.DFAmetricsPanel);
            app.Alpha1EditFieldLabel.Text = {'Alpha 1'; ''};
            app.Alpha1EditField = uieditfield(app.DFAmetricsPanel, 'text');
            app.Alpha1EditField.Editable = 'off';
            app.Alpha1EditField.Tooltip = {'Short term fluctuation slope'; ''; '\alpha = 1.5: Brown noise (integral of white noise)'; '1 < \alpha < 1.5: Different kinds of noise'; '\alpha = 1: 1/f noise'; '0.5 < \alpha < 1:Large values are likely to be followed by large value and vice versa'; '\alpha = 0.5: White noise'; '0 < \alpha < 0.5: Large value is likely to be followed by small value and vice versa'};
            app.Alpha2EditFieldLabel = uilabel(app.DFAmetricsPanel);
            app.Alpha2EditFieldLabel.Text = 'Alpha 2';
            app.Alpha2EditField = uieditfield(app.DFAmetricsPanel, 'text');
            app.Alpha2EditField.Editable = 'off';
            app.Alpha2EditField.Tooltip = {'Long term fluctuation slope'};
            app.ControlparametersPanel = uipanel(app.DFATab);
            app.ControlparametersPanel.Title = 'Control parameters';
            app.SamplevectorinterpolationButtonGroup = uibuttongroup(app.ControlparametersPanel);
            app.SamplevectorinterpolationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SamplevectorinterpolationButtonGroupSelectionChanged, true);
            app.SamplevectorinterpolationButtonGroup.Title = 'Sample vector interpolation';
            app.LinearButton = uiradiobutton(app.SamplevectorinterpolationButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.LogarithmicButton = uiradiobutton(app.SamplevectorinterpolationButtonGroup);
            app.LogarithmicButton.Text = 'Logarithmic';
            app.LeastSquareRegressionorderLabel = uilabel(app.ControlparametersPanel);
            app.LeastSquareRegressionorderLabel.Text = {'Least Square'; 'Regression order'};
            app.LeastSquareRegressionorderEditField = uieditfield(app.ControlparametersPanel, 'numeric');
            app.LeastSquareRegressionorderEditField.ValueChangedFcn = createCallbackFcn(app, @LeastSquareRegressionorderEditFieldValueChanged, true);
            app.LeastSquareRegressionorderEditField.Value = 1;
            app.BreakpointbeatsEditFieldLabel = uilabel(app.ControlparametersPanel);
            app.BreakpointbeatsEditFieldLabel.Text = 'Breakpoint (beats)';
            app.BreakpointbeatsEditField = uieditfield(app.ControlparametersPanel, 'numeric');
            app.BreakpointbeatsEditField.ValueChangedFcn = createCallbackFcn(app, @BreakpointbeatsEditFieldValueChanged, true);
            app.BreakpointbeatsEditField.Value = 12;
            app.MinimumsegmentsizeEditFieldLabel = uilabel(app.ControlparametersPanel);
            app.MinimumsegmentsizeEditFieldLabel.Text = 'Minimum segment size';
            app.MinimumsegmentsizeEditField = uieditfield(app.ControlparametersPanel, 'numeric');
            app.MinimumsegmentsizeEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumsegmentsizeEditFieldValueChanged, true);
            app.MinimumsegmentsizeEditField.Tooltip = {'Minimum segment size (in beats)'};
            app.MinimumsegmentsizeEditField.Value = 4;
            app.MaximumsegmentsizeEditFieldLabel = uilabel(app.ControlparametersPanel);
            app.MaximumsegmentsizeEditFieldLabel.Text = 'Maximum segment size';
            app.MaximumsegmentsizeEditField = uieditfield(app.ControlparametersPanel, 'numeric');
            app.MaximumsegmentsizeEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumsegmentsizeEditFieldValueChanged, true);
            app.MaximumsegmentsizeEditField.Tooltip = {'Maximum segment size (in beats)'};
            app.MaximumsegmentsizeEditField.Value = 64;
            app.NumberofsegmentsizesEditFieldLabel = uilabel(app.ControlparametersPanel);
            app.NumberofsegmentsizesEditFieldLabel.Text = 'Number of segment sizes';
            app.NumberofsegmentsizesEditField = uieditfield(app.ControlparametersPanel, 'numeric');
            app.NumberofsegmentsizesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofsegmentsizesEditFieldValueChanged, true);
            app.NumberofsegmentsizesEditField.Value = 100;
            app.EntropyTab = uitab(app.TabGroup3);
            app.EntropyTab.Title = 'Entropy';
            app.ApproximateEntropyPanel = uipanel(app.EntropyTab);
            app.ApproximateEntropyPanel.Title = 'Approximate Entropy';
            app.ApproximateEntropyLabel = uilabel(app.ApproximateEntropyPanel);
            app.ApproximateEntropyLabel.Text = 'Approximate Entropy';
            app.ApproximateEntropyEditField = uieditfield(app.ApproximateEntropyPanel, 'numeric');
            app.ApproximateEntropyEditField.Editable = 'off';
            app.ApproximateEntropyEditField.Tooltip = {'Approximate entropy (ApEn) measures the complexity or irregularity of the signal.'; 'Large values of ApEn indicate high irregularity and smaller values of ApEn a more regular signal.'};
            app.TolerancerEditFieldLabel = uilabel(app.ApproximateEntropyPanel);
            app.TolerancerEditFieldLabel.Text = 'Tolerance (r)';
            app.TolerancerEditField = uieditfield(app.ApproximateEntropyPanel, 'numeric');
            app.TolerancerEditField.ValueChangedFcn = createCallbackFcn(app, @TolerancerEditFieldValueChanged, true);
            app.ResetButton = uibutton(app.ApproximateEntropyPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = 'reset.png';
            app.ResetButton.Text = 'Reset';
            app.EmbeddingdimensionEditFieldLabel = uilabel(app.ApproximateEntropyPanel);
            app.EmbeddingdimensionEditFieldLabel.Text = 'Embedding dimension';
            app.EmbeddingdimensionEditField = uieditfield(app.ApproximateEntropyPanel, 'numeric');
            app.EmbeddingdimensionEditField.ValueChangedFcn = createCallbackFcn(app, @EmbeddingdimensionEditFieldValueChanged, true);
            app.EmbeddingdimensionEditField.Value = 2;
            app.MultiscaleEntropyMSEPanel = uipanel(app.EntropyTab);
            app.MultiscaleEntropyMSEPanel.Title = 'Multiscale Entropy (MSE)';
            app.UIAxes10 = uiaxes(app.MultiscaleEntropyMSEPanel);
            title(app.UIAxes10, 'Title')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            app.MaxscaleEditFieldLabel = uilabel(app.MultiscaleEntropyMSEPanel);
            app.MaxscaleEditFieldLabel.Text = 'Max. scale';
            app.MaxscaleEditField = uieditfield(app.MultiscaleEntropyMSEPanel, 'numeric');
            app.MaxscaleEditField.ValueChangedFcn = createCallbackFcn(app, @MaxscaleEditFieldValueChanged, true);
            app.MaxscaleEditField.Value = 15;
            app.SampleEntropyPanel = uipanel(app.EntropyTab);
            app.SampleEntropyPanel.Title = 'Sample Entropy';
            app.ResetButton_2 = uibutton(app.SampleEntropyPanel, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Icon = 'reset.png';
            app.ResetButton_2.Text = 'Reset';
            app.TolerancerEditField_2Label = uilabel(app.SampleEntropyPanel);
            app.TolerancerEditField_2Label.Text = 'Tolerance (r)';
            app.TolerancerEditField_2 = uieditfield(app.SampleEntropyPanel, 'numeric');
            app.TolerancerEditField_2.ValueChangedFcn = createCallbackFcn(app, @TolerancerEditField_2ValueChanged, true);
            app.EmbeddingdimensionEditField_2Label = uilabel(app.SampleEntropyPanel);
            app.EmbeddingdimensionEditField_2Label.Text = 'Embedding dimension';
            app.EmbeddingdimensionEditField_2 = uieditfield(app.SampleEntropyPanel, 'numeric');
            app.EmbeddingdimensionEditField_2.ValueChangedFcn = createCallbackFcn(app, @EmbeddingdimensionEditField_2ValueChanged, true);
            app.EmbeddingdimensionEditField_2.Value = 2;
            app.SampleEntropyEditFieldLabel = uilabel(app.SampleEntropyPanel);
            app.SampleEntropyEditFieldLabel.Text = 'Sample Entropy';
            app.SampleEntropyEditField = uieditfield(app.SampleEntropyPanel, 'numeric');
            app.SampleEntropyEditField.Editable = 'off';
            app.SampleEntropyEditField.Tooltip = {'Sample entropy (SampEn) is similar to ApEn'; ' but and important difference is present:'; 'The comparison of vectors does not compare against itself anymore (eliminating bias)'};
            app.RecurrenceplotTab = uitab(app.TabGroup3);
            app.RecurrenceplotTab.Title = 'Recurrence plot';
            app.UIAxes9 = uiaxes(app.RecurrenceplotTab);
            title(app.UIAxes9, 'Title')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            app.RecurrenceplotmetricsPanel = uipanel(app.RecurrenceplotTab);
            app.RecurrenceplotmetricsPanel.Title = 'Recurrence plot metrics';
            app.ShannonentropyEditFieldLabel = uilabel(app.RecurrenceplotmetricsPanel);
            app.ShannonentropyEditFieldLabel.Text = 'Shannon entropy';
            app.ShannonentropyEditField = uieditfield(app.RecurrenceplotmetricsPanel, 'numeric');
            app.ShannonentropyEditField.Editable = 'off';
            app.DETEditFieldLabel = uilabel(app.RecurrenceplotmetricsPanel);
            app.DETEditFieldLabel.Text = 'DET';
            app.DETEditField = uieditfield(app.RecurrenceplotmetricsPanel, 'numeric');
            app.DETEditField.Editable = 'off';
            app.RecurrencerateRECEditField_2Label = uilabel(app.RecurrenceplotmetricsPanel);
            app.RecurrencerateRECEditField_2Label.Text = 'Recurrence rate (REC)';
            app.RecurrencerateRECEditField_2 = uieditfield(app.RecurrenceplotmetricsPanel, 'text');
            app.RecurrencerateRECEditField_2.Editable = 'off';
            app.RecurrencerateRECEditField_2.Tooltip = {'The recurrence rate is the ratio of ones and zeros in the RP matrix.'};
            app.RecurrenceparameterscontrolPanel = uipanel(app.RecurrenceplotTab);
            app.RecurrenceparameterscontrolPanel.Title = 'Recurrence parameters control';
            app.ThresholdrEditFieldLabel = uilabel(app.RecurrenceparameterscontrolPanel);
            app.ThresholdrEditFieldLabel.Text = 'Threshold (r)';
            app.ThresholdrEditField = uieditfield(app.RecurrenceparameterscontrolPanel, 'numeric');
            app.ThresholdrEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdrEditFieldValueChanged, true);
            app.EmbeddingdimensionEditField_3Label = uilabel(app.RecurrenceparameterscontrolPanel);
            app.EmbeddingdimensionEditField_3Label.Text = 'Embedding dimension';
            app.EmbeddingdimensionEditField_3 = uieditfield(app.RecurrenceparameterscontrolPanel, 'numeric');
            app.EmbeddingdimensionEditField_3.ValueChangedFcn = createCallbackFcn(app, @EmbeddingdimensionEditField_3ValueChanged, true);
            app.EmbeddingdimensionEditField_3.Value = 10;
            app.LagEditField_2Label = uilabel(app.RecurrenceparameterscontrolPanel);
            app.LagEditField_2Label.Text = 'Lag';
            app.LagEditField_2 = uieditfield(app.RecurrenceparameterscontrolPanel, 'numeric');
            app.LagEditField_2.ValueChangedFcn = createCallbackFcn(app, @LagEditField_2ValueChanged, true);
            app.LagEditField_2.Value = 1;
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.GeneratereportButton = uibutton(app.ReportTab, 'push');
            app.GeneratereportButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratereportButtonPushed, true);
            app.GeneratereportButton.Icon = 'generate report.png';
            app.GeneratereportButton.Text = {'Generate'; 'report'};
            app.FilenameEditField_2Label = uilabel(app.ReportTab);
            app.FilenameEditField_2Label.Text = 'File name';
            app.FilenameEditField_2 = uieditfield(app.ReportTab, 'text');
            app.FilenameEditField_2.Value = 'results_report';
            app.PathtosaveEditFieldLabel = uilabel(app.ReportTab);
            app.PathtosaveEditFieldLabel.Text = 'Path to save';
            app.PathtosaveEditField = uieditfield(app.ReportTab, 'text');
            app.BrowseButton = uibutton(app.ReportTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
        end
    end

    methods (Access = public)

        % Construct app
        function app = programa

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HRVMetricsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HRVMetricsUIFigure)
        end
    end
end